classdef ER_DLC_MoveCheck < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FINETUNEFRAMEPanel     matlab.ui.container.Panel
        FrameStep              matlab.ui.control.Spinner
        FrameStepSpinnerLabel  matlab.ui.control.Label
        FrameNEXTButton        matlab.ui.control.Button
        FrameBACKButton        matlab.ui.control.Button
        FrameLabel             matlab.ui.control.Label
        VidImage               matlab.ui.control.Image
        MOVEINFOPanel          matlab.ui.container.Panel
        CONFIRMButton          matlab.ui.control.Button
        EndButton              matlab.ui.control.Button
        BeginButton            matlab.ui.control.Button
        MoveTypeS              matlab.ui.control.DropDown
        MoveIDDropDownLabel    matlab.ui.control.Label
        MoveNumber             matlab.ui.control.Spinner
        MoveSpinnerLabel       matlab.ui.control.Label
        MoveTable              matlab.ui.control.Table
        FramesSlider           matlab.ui.control.Slider
        FramesSliderLabel      matlab.ui.control.Label
        FILEOptionsPanel       matlab.ui.container.Panel
        EXPORTCSVButton        matlab.ui.control.Button
        LOADVIDEOButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        vidfileLOC % Location of video
        vidfileNAME % Name of video
        dlcMainData
        frameNtotal
        frameCurrent
        moveTabCount
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADVIDEOButton
        function LOADVIDEOButtonPushed(app, event)

            % UIGETFILE for video
            [app.vidfileNAME , app.vidfileLOC] = uigetfile('*.mp4');

            cd(app.vidfileLOC);

            loadINFO = uiprogressdlg(app.UIFigure,'Title','Load and Processing Video file',...
                'Indeterminate','on');
            drawnow

            % Load video file into matlab
            tab_vidObj = VideoReader(app.vidfileNAME);
            tab_vid = struct('cdata',zeros(tab_vidObj.Height,tab_vidObj.Width,3,'uint8'),'colormap',[]);

            frami = 1;
            while hasFrame(tab_vidObj)
                tab_vid(frami).cdata = readFrame(tab_vidObj);
                frami = frami+1;
            end
            app.dlcMainData = struct2table(tab_vid);

            close(loadINFO)
            
            % imshow(app.dlcMainData.cdata{1},'Parent',app.VidDisp);

            app.VidImage.ImageSource = app.dlcMainData.cdata{1};
            app.VidImage.ScaleMethod = 'stretch';

            % title(app.VidDisp,['Frame ',num2str(1)])

            app.frameNtotal = height(app.dlcMainData);
            app.FramesSlider.Limits = [1 app.frameNtotal];
            app.FramesSlider.MajorTicks = 1:250:app.frameNtotal;
            app.frameCurrent = 1;
            app.FrameLabel.Text = ['Frame ' , num2str(1)];
            app.moveTabCount = 1;

            currROW = uistyle('BackgroundColor', [1 0.5 0.5]);
            addStyle(app.MoveTable, currROW, 'row', 1);

            setTABLE = table(zeros(100,1),repmat({''},100,1),zeros(100,1),...
                zeros(100,1),'VariableNames',{'MoveN','MoveType','BeginF','EndF'});
            app.MoveTable.Data = setTABLE;


        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            changingValue = round(event.Value);

            app.VidImage.ImageSource = app.dlcMainData.cdata{changingValue};
            app.FrameLabel.Text = ['Frame ' , num2str(changingValue)];
            app.frameCurrent = changingValue;

        end

        % Button pushed function: FrameNEXTButton
        function FrameNEXTButtonPushed(app, event)
            
            newFRAME = app.frameCurrent + app.FrameStep.Value;
            if newFRAME > app.frameNtotal
                return
            else
                app.VidImage.ImageSource = app.dlcMainData.cdata{newFRAME};
                app.FrameLabel.Text = ['Frame ' , num2str(newFRAME)];
                app.frameCurrent = newFRAME;
                app.FramesSlider.Value = newFRAME;
            end

        end

        % Button pushed function: FrameBACKButton
        function FrameBACKButtonPushed(app, event)
            
            newFRAME = app.frameCurrent - app.FrameStep.Value;
            if newFRAME < 1
                return
            else
                app.VidImage.ImageSource = app.dlcMainData.cdata{newFRAME};
                app.FrameLabel.Text = ['Frame ' , num2str(newFRAME)];
                app.frameCurrent = newFRAME;
                app.FramesSlider.Value = newFRAME;
            end

        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            
            % Use MoveCount to determine table row
            app.MoveTable.Data.MoveN(app.moveTabCount) = app.MoveNumber.Value;
            app.MoveTable.Data.MoveType{app.moveTabCount} = app.MoveTypeS.Value;
            app.MoveTable.Data.BeginF(app.moveTabCount) = app.frameCurrent;

        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            
            app.MoveTable.Data.MoveN(app.moveTabCount) = app.MoveNumber.Value;
            app.MoveTable.Data.MoveType{app.moveTabCount} = app.MoveTypeS.Value;
            app.MoveTable.Data.EndF(app.moveTabCount) = app.frameCurrent;

        end

        % Button pushed function: CONFIRMButton
        function CONFIRMButtonPushed(app, event)
            

            % USE table highlighting
            app.moveTabCount = app.moveTabCount + 1;

            tmpROWS = 1:100;
            remainROWS = find(tmpROWS ~= app.moveTabCount);

            currROW = uistyle('BackgroundColor', [1 0.5 0.5]);
            remROWS = uistyle('BackgroundColor', [0.94 0.94 0.94]);
            addStyle(app.MoveTable, currROW, 'row', app.moveTabCount);
            addStyle(app.MoveTable, remROWS, 'row', remainROWS);


        end

        % Button pushed function: EXPORTCSVButton
        function EXPORTCSVButtonPushed(app, event)
            

            selection = uiconfirm(app.UIFigure,...
                "SAVE CSV","FINISHED WITH VIDEO???", "Icon","info");

            switch selection
                case 'OK'
                    cd(app.vidfileLOC);
                    tmpFname = app.vidfileNAME;
                    [~,fNamepart,~]= fileparts(tmpFname);
                    csvFname = [fNamepart , '_MoveIndex.csv'];
                    writetable(app.MoveTable.Data , csvFname);

                case 'Cancel'
                    return
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FILEOptionsPanel = uipanel(app.UIFigure);
            app.FILEOptionsPanel.Title = 'FILE Options';
            app.LOADVIDEOButton = uibutton(app.FILEOptionsPanel, 'push');
            app.LOADVIDEOButton.ButtonPushedFcn = createCallbackFcn(app, @LOADVIDEOButtonPushed, true);
            app.LOADVIDEOButton.Text = 'LOAD VIDEO';
            app.EXPORTCSVButton = uibutton(app.FILEOptionsPanel, 'push');
            app.EXPORTCSVButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTCSVButtonPushed, true);
            app.EXPORTCSVButton.Text = 'EXPORT CSV';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.MoveTable = uitable(app.UIFigure);
            app.MoveTable.ColumnName = {'MoveN'; 'MoveType'; 'BeginF'; 'EndF'};
            app.MoveTable.RowName = {};
            app.MOVEINFOPanel = uipanel(app.UIFigure);
            app.MOVEINFOPanel.Title = 'MOVE INFO';
            app.MoveSpinnerLabel = uilabel(app.MOVEINFOPanel);
            app.MoveSpinnerLabel.Text = 'Move#';
            app.MoveNumber = uispinner(app.MOVEINFOPanel);
            app.MoveNumber.Limits = [1 25];
            app.MoveNumber.Value = 1;
            app.MoveIDDropDownLabel = uilabel(app.MOVEINFOPanel);
            app.MoveIDDropDownLabel.Text = 'MoveID';
            app.MoveTypeS = uidropdown(app.MOVEINFOPanel);
            app.MoveTypeS.Items = {'HAND OC', 'HAND PS', 'ARM EF', 'FINGER TAP', 'TREMOR'};
            app.MoveTypeS.Value = 'HAND OC';
            app.BeginButton = uibutton(app.MOVEINFOPanel, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.EndButton = uibutton(app.MOVEINFOPanel, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.CONFIRMButton = uibutton(app.MOVEINFOPanel, 'push');
            app.CONFIRMButton.ButtonPushedFcn = createCallbackFcn(app, @CONFIRMButtonPushed, true);
            app.CONFIRMButton.Text = 'CONFIRM';
            app.VidImage = uiimage(app.UIFigure);
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame #';
            app.FINETUNEFRAMEPanel = uipanel(app.UIFigure);
            app.FINETUNEFRAMEPanel.Title = 'FINE TUNE FRAME';
            app.FrameBACKButton = uibutton(app.FINETUNEFRAMEPanel, 'push');
            app.FrameBACKButton.ButtonPushedFcn = createCallbackFcn(app, @FrameBACKButtonPushed, true);
            app.FrameBACKButton.Text = 'Frame BACK';
            app.FrameNEXTButton = uibutton(app.FINETUNEFRAMEPanel, 'push');
            app.FrameNEXTButton.ButtonPushedFcn = createCallbackFcn(app, @FrameNEXTButtonPushed, true);
            app.FrameNEXTButton.Text = 'Frame NEXT';
            app.FrameStepSpinnerLabel = uilabel(app.FINETUNEFRAMEPanel);
            app.FrameStepSpinnerLabel.Text = 'FrameStep';
            app.FrameStep = uispinner(app.FINETUNEFRAMEPanel);
            app.FrameStep.Step = 5;
            app.FrameStep.Limits = [1 100];
            app.FrameStep.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ER_DLC_MoveCheck

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end