classdef ER_DLC_MoveCheck_Dual_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FILEOptionsPanel_2     matlab.ui.container.Panel
        useSingleVid           matlab.ui.control.Button
        LOADPRIORCSVButton     matlab.ui.control.Button
        LOADLEFTCamera         matlab.ui.control.Button
        LOADRightCamera        matlab.ui.control.Button
        FINETUNEFRAMEPanel     matlab.ui.container.Panel
        FrameStep              matlab.ui.control.Spinner
        FrameStepSpinnerLabel  matlab.ui.control.Label
        FrameNEXTButton        matlab.ui.control.Button
        FrameBACKButton        matlab.ui.control.Button
        FrameLabel1            matlab.ui.control.Label
        FrameLabel2            matlab.ui.control.Label
        VidImage1              matlab.ui.control.Image
        VidImage2              matlab.ui.control.Image
        MOVEINFOPanel          matlab.ui.container.Panel
        CONFIRMButton          matlab.ui.control.Button
        EndButton              matlab.ui.control.Button
        BeginButton            matlab.ui.control.Button
        MoveTypeS              matlab.ui.control.DropDown
        MoveIDDropDownLabel    matlab.ui.control.Label
        MoveNumber             matlab.ui.control.Spinner
        MoveSpinnerLabel       matlab.ui.control.Label
        MoveTable              matlab.ui.control.Table
        FramesSlider           matlab.ui.control.Slider
        FramesSliderLabel      matlab.ui.control.Label
        EXPORTPanel            matlab.ui.container.Panel
        SAVEDirDropDown        matlab.ui.control.DropDown
        SAVEDirDropDownLabel   matlab.ui.control.Label
        EXPORTCSVButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        LEFTvidfileLOC % Location of video
        LEFTvidfileNAME % Name of video
        LEFTdlcMainData
        RIGHTvidfileLOC
        RIGHTvidfileNAME
        RIGHTdlcMainData
        frameNtotal
        frameCurrent
        moveTabCount
        OLDCSVfile % Description
        OLDCSVfileLOC % Description
        oldCSVschk % Description
        useSecondV
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.oldCSVschk = 0;
            app.useSecondV = 1;
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            
            %%%% ADD A BOUNDARY CHECK
            
            changingValue = round(event.Value);

            % VIDEO LEFT
            app.VidImage1.ImageSource = app.LEFTdlcMainData.cdata{changingValue};
            app.FrameLabel1.Text = ['Frame ' , num2str(changingValue)];

            if app.useSecondV
                % VIDEO RIGHT
                app.VidImage2.ImageSource = app.RIGHTdlcMainData.cdata{changingValue};
                app.FrameLabel2.Text = ['Frame ' , num2str(changingValue)];
            end

            app.frameCurrent = changingValue;

        end

        % Button pushed function: FrameNEXTButton
        function FrameNEXTButtonPushed(app, event)
            
            newFRAME = app.frameCurrent + app.FrameStep.Value;
            if newFRAME > app.frameNtotal
                return
            else
                % VIDEO LEFT
                app.VidImage1.ImageSource = app.LEFTdlcMainData.cdata{newFRAME};
                app.FrameLabel1.Text = ['Frame ' , num2str(newFRAME)];

                if app.useSecondV

                    % VIDEO RIGHT
                    app.VidImage2.ImageSource = app.RIGHTdlcMainData.cdata{newFRAME};
                    app.FrameLabel2.Text = ['Frame ' , num2str(newFRAME)];

                end

                app.frameCurrent = newFRAME;
                app.FramesSlider.Value = newFRAME;
            end

        end

        % Button pushed function: FrameBACKButton
        function FrameBACKButtonPushed(app, event)
            
            newFRAME = app.frameCurrent - app.FrameStep.Value;
            if newFRAME < 1
                return
            else
                % VIDEO LEFT
                app.VidImage1.ImageSource = app.LEFTdlcMainData.cdata{newFRAME};
                app.FrameLabel1.Text = ['Frame ' , num2str(newFRAME)];

                if app.useSecondV

                    % VIDEO RIGHT
                    app.VidImage2.ImageSource = app.RIGHTdlcMainData.cdata{newFRAME};
                    app.FrameLabel2.Text = ['Frame ' , num2str(newFRAME)];

                end

                app.frameCurrent = newFRAME;
                app.FramesSlider.Value = newFRAME;
            end

        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            
            % Use MoveCount to determine table row
            app.MoveTable.Data.MoveN(app.moveTabCount) = app.MoveNumber.Value;
            app.MoveTable.Data.MoveType{app.moveTabCount} = app.MoveTypeS.Value;
            app.MoveTable.Data.BeginF(app.moveTabCount) = app.frameCurrent;

        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            
            app.MoveTable.Data.MoveN(app.moveTabCount) = app.MoveNumber.Value;
            app.MoveTable.Data.MoveType{app.moveTabCount} = app.MoveTypeS.Value;
            app.MoveTable.Data.EndF(app.moveTabCount) = app.frameCurrent;

        end

        % Button pushed function: CONFIRMButton
        function CONFIRMButtonPushed(app, event)
            

            % USE table highlighting
            app.moveTabCount = app.moveTabCount + 1;

            tmpROWS = 1:100;
            remainROWS = find(tmpROWS ~= app.moveTabCount);

            currROW = uistyle('BackgroundColor', [1 0.5 0.5]);
            remROWS = uistyle('BackgroundColor', [0.94 0.94 0.94]);
            addStyle(app.MoveTable, currROW, 'row', app.moveTabCount);
            addStyle(app.MoveTable, remROWS, 'row', remainROWS);


        end

        % Button pushed function: EXPORTCSVButton
        function EXPORTCSVButtonPushed(app, event)
            

            selection = uiconfirm(app.UIFigure,...
                "SAVE CSV","FINISHED WITH VIDEO???", "Icon","info");

            switch selection
                case 'OK'

                    if app.oldCSVschk == 0

                        saveLocation = app.SAVEDirDropDown.Value;
                        switch saveLocation
                            case 'LeftVidLoc'
                                cd(app.LEFTvidfileLOC);
                                tmpFname = app.LEFTvidfileNAME;
                                [~,fNamepart,~]= fileparts(tmpFname);
                            case 'RightVidLoc'
                                cd(app.RIGHTvidfileLOC);
                                tmpFname = app.RIGHTvidfileNAME;
                                [~,fNamepart,~]= fileparts(tmpFname);
                            case 'User Select'
                                userSelect = uigetdir();
                                cd(userSelect)
                                tmpFname = app.LEFTvidfileNAME;
                                [~,fNamepart,~]= fileparts(tmpFname);
                        end

                        csvFname = [fNamepart , '_MoveIndex.csv'];

                    else

                        cd(app.OLDCSVfileLOC);
                        csvFname = app.OLDCSVfile;

                    end

                    writetable(app.MoveTable.Data , csvFname);

                case 'Cancel'
                    return
            end


        end

        % Button pushed function: LOADLEFTCamera
        function LOADLEFTCameraButtonPushed(app, event)
            
            % UIGETFILE for video
            [app.LEFTvidfileNAME , app.LEFTvidfileLOC] = uigetfile('*.mp4');

            cd(app.LEFTvidfileLOC);

            loadINFO = uiprogressdlg(app.UIFigure,'Title','Load and Processing Video file',...
                'Indeterminate','on');
            drawnow

            % Load video file into matlab
            tab_vidObj = VideoReader(app.LEFTvidfileNAME);
            tab_vid = struct('cdata',zeros(tab_vidObj.Height,tab_vidObj.Width,3,'uint8'),'colormap',[]);

            frami = 1;
            while hasFrame(tab_vidObj)
                tab_vid(frami).cdata = readFrame(tab_vidObj);
                frami = frami+1;
            end
            app.LEFTdlcMainData = struct2table(tab_vid);

            close(loadINFO)
            
            % imshow(app.dlcMainData.cdata{1},'Parent',app.VidDisp);

            app.VidImage1.ImageSource = app.LEFTdlcMainData.cdata{1};
            app.VidImage1.ScaleMethod = 'stretch';

            % title(app.VidDisp,['Frame ',num2str(1)])

            app.frameNtotal = height(app.LEFTdlcMainData);
            app.FramesSlider.Limits = [1 app.frameNtotal];
            app.FramesSlider.MajorTicks = 1:250:app.frameNtotal;
            app.frameCurrent = 1;
            app.FrameLabel1.Text = ['Frame ' , num2str(1)];
            % app.FrameLabel2.Text = ['Frame ' , num2str(1)];
            app.moveTabCount = 1;

            currROW = uistyle('BackgroundColor', [1 0.5 0.5]);
            addStyle(app.MoveTable, currROW, 'row', 1);

            setTABLE = table(zeros(100,1),repmat({''},100,1),zeros(100,1),...
                zeros(100,1),'VariableNames',{'MoveN','MoveType','BeginF','EndF'});
            app.MoveTable.Data = setTABLE;

            app.LOADRightCamera.Enable = 'on';
            app.useSingleVid.Enable = "on";

        end

        % Button pushed function: LOADRightCamera
        function LOADRightCameraButtonPushed(app, event)
            
            [app.RIGHTvidfileNAME , app.RIGHTvidfileLOC] = uigetfile('*.mp4');

            cd(app.RIGHTvidfileLOC);

            loadINFO = uiprogressdlg(app.UIFigure,'Title','Load and Processing Video file',...
                'Indeterminate','on');
            drawnow

            % Load video file into matlab
            tab_vidObj = VideoReader(app.RIGHTvidfileNAME);
            tab_vid = struct('cdata',zeros(tab_vidObj.Height,tab_vidObj.Width,3,'uint8'),'colormap',[]);

            frami = 1;
            while hasFrame(tab_vidObj)
                tab_vid(frami).cdata = readFrame(tab_vidObj);
                frami = frami+1;
            end
            app.RIGHTdlcMainData = struct2table(tab_vid);

            close(loadINFO)
            
            app.VidImage2.ImageSource = app.RIGHTdlcMainData.cdata{1};
            app.VidImage2.ScaleMethod = 'stretch';

            % app.frameNtotal = height(app.LEFTdlcMainData);
            % app.FramesSlider.Limits = [1 app.frameNtotal];
            % app.FramesSlider.MajorTicks = 1:250:app.frameNtotal;
            % app.frameCurrent = 1;
            app.FrameLabel2.Text = ['Frame ' , num2str(1)];
            % app.FrameLabel2.Text = ['Frame ' , num2str(1)];
            % app.moveTabCount = 1;

            currROW = uistyle('BackgroundColor', [1 0.5 0.5]);
            addStyle(app.MoveTable, currROW, 'row', 1);

            setTABLE = table(zeros(100,1),repmat({''},100,1),zeros(100,1),...
                zeros(100,1),'VariableNames',{'MoveN','MoveType','BeginF','EndF'});
            app.MoveTable.Data = setTABLE;

            app.LOADPRIORCSVButton.Enable = "on";

            app.EXPORTPanel.Enable = 'on';
            app.FINETUNEFRAMEPanel.Enable = 'on';
            app.MOVEINFOPanel.Enable = 'on';
            app.MoveTable.Enable = 'on';
            app.FramesSlider.Enable = 'on';

        end

        % Value changed function: MoveTypeS
        function MoveTypeSValueChanged(app, event)
            value = app.MoveTypeS.Value;
            

            % CHECK EXISTENCE AND MAX COUNT IN TABLE TO PRE-SELECT COUNTer
            % app.MoveNumber
            % if isempty(matches(movetable,moveID))
            % counter = 1
            % else
            % find rows
            % find max
            % counter = max + 1
            %


        end

        % Button pushed function: LOADPRIORCSVButton
        function LOADPRIORCSVButtonPushed(app, event)
            [app.OLDCSVfile , app.OLDCSVfileLOC] = uigetfile('*.csv');

            cd(app.OLDCSVfileLOC);

            loadINFO = uiprogressdlg(app.UIFigure,'Title','Loading old CSV',...
                'Indeterminate','on');

            oldCSVtab = readtable(app.OLDCSVfile);

            app.MoveTable.Data = oldCSVtab;

            tmpZeroRow = oldCSVtab.MoveN;
            newMoveTabcount = find(tmpZeroRow == 0,1,'first');
            app.moveTabCount = newMoveTabcount;

            tmpROWS = 1:100;
            remainROWS = find(tmpROWS ~= app.moveTabCount);

            currROW = uistyle('BackgroundColor', [1 0.5 0.5]);
            remROWS = uistyle('BackgroundColor', [0.94 0.94 0.94]);
            addStyle(app.MoveTable, currROW, 'row', app.moveTabCount);
            addStyle(app.MoveTable, remROWS, 'row', remainROWS);

            close(loadINFO)
            app.oldCSVschk = 1;
            app.SAVEDirDropDown.Enable = "off";

        end

        % Button pushed function: useSingleVid
        function useSingleVidButtonPushed(app, event)
            

            app.useSecondV = 0;


            %%% ADD PROPERTY TO SWITCH CASE OFF SECOND FRAME

            currROW = uistyle('BackgroundColor', [1 0.5 0.5]);
            addStyle(app.MoveTable, currROW, 'row', 1);

            setTABLE = table(zeros(100,1),repmat({''},100,1),zeros(100,1),...
                zeros(100,1),'VariableNames',{'MoveN','MoveType','BeginF','EndF'});
            app.MoveTable.Data = setTABLE;

            app.LOADPRIORCSVButton.Enable = "on";

            app.EXPORTPanel.Enable = 'on';
            app.FINETUNEFRAMEPanel.Enable = 'on';
            app.MOVEINFOPanel.Enable = 'on';
            app.MoveTable.Enable = 'on';
            app.FramesSlider.Enable = 'on';
            app.LOADRightCamera.Enable = "off";
            app.useSingleVid.Enable = 'off';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EXPORTPanel = uipanel(app.UIFigure);
            app.EXPORTPanel.Enable = 'off';
            app.EXPORTPanel.Title = 'EXPORT';
            app.EXPORTCSVButton = uibutton(app.EXPORTPanel, 'push');
            app.EXPORTCSVButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTCSVButtonPushed, true);
            app.EXPORTCSVButton.Text = 'EXPORT CSV';
            app.SAVEDirDropDownLabel = uilabel(app.EXPORTPanel);
            app.SAVEDirDropDownLabel.Text = 'SAVE Dir';
            app.SAVEDirDropDown = uidropdown(app.EXPORTPanel);
            app.SAVEDirDropDown.Items = {'LeftVidLoc', 'RightVidLoc', 'User Select'};
            app.SAVEDirDropDown.Value = 'LeftVidLoc';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Enable = 'off';
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.Enable = 'off';
            app.MoveTable = uitable(app.UIFigure);
            app.MoveTable.ColumnName = {'MoveN'; 'MoveType'; 'BeginF'; 'EndF'};
            app.MoveTable.RowName = {};
            app.MoveTable.Enable = 'off';
            app.MOVEINFOPanel = uipanel(app.UIFigure);
            app.MOVEINFOPanel.Enable = 'off';
            app.MOVEINFOPanel.Title = 'MOVE INFO';
            app.MoveSpinnerLabel = uilabel(app.MOVEINFOPanel);
            app.MoveSpinnerLabel.Text = 'Move#';
            app.MoveNumber = uispinner(app.MOVEINFOPanel);
            app.MoveNumber.Limits = [1 25];
            app.MoveNumber.Value = 1;
            app.MoveIDDropDownLabel = uilabel(app.MOVEINFOPanel);
            app.MoveIDDropDownLabel.Text = 'MoveID';
            app.MoveTypeS = uidropdown(app.MOVEINFOPanel);
            app.MoveTypeS.Items = {'HAND OC', 'HAND PS', 'ARM EF', 'FINGER TAP', 'TREMOR', 'REST', 'LFP TABLET'};
            app.MoveTypeS.ValueChangedFcn = createCallbackFcn(app, @MoveTypeSValueChanged, true);
            app.MoveTypeS.Value = 'HAND OC';
            app.BeginButton = uibutton(app.MOVEINFOPanel, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.EndButton = uibutton(app.MOVEINFOPanel, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.CONFIRMButton = uibutton(app.MOVEINFOPanel, 'push');
            app.CONFIRMButton.ButtonPushedFcn = createCallbackFcn(app, @CONFIRMButtonPushed, true);
            app.CONFIRMButton.Text = 'CONFIRM';
            app.VidImage2 = uiimage(app.UIFigure);
            app.VidImage1 = uiimage(app.UIFigure);
            app.FrameLabel2 = uilabel(app.UIFigure);
            app.FrameLabel2.Text = 'Frame #';
            app.FrameLabel1 = uilabel(app.UIFigure);
            app.FrameLabel1.Text = 'Frame #';
            app.FINETUNEFRAMEPanel = uipanel(app.UIFigure);
            app.FINETUNEFRAMEPanel.Enable = 'off';
            app.FINETUNEFRAMEPanel.Title = 'FINE TUNE FRAME';
            app.FrameBACKButton = uibutton(app.FINETUNEFRAMEPanel, 'push');
            app.FrameBACKButton.ButtonPushedFcn = createCallbackFcn(app, @FrameBACKButtonPushed, true);
            app.FrameBACKButton.Text = 'Frame BACK';
            app.FrameNEXTButton = uibutton(app.FINETUNEFRAMEPanel, 'push');
            app.FrameNEXTButton.ButtonPushedFcn = createCallbackFcn(app, @FrameNEXTButtonPushed, true);
            app.FrameNEXTButton.Text = 'Frame NEXT';
            app.FrameStepSpinnerLabel = uilabel(app.FINETUNEFRAMEPanel);
            app.FrameStepSpinnerLabel.Text = 'FrameStep';
            app.FrameStep = uispinner(app.FINETUNEFRAMEPanel);
            app.FrameStep.Step = 5;
            app.FrameStep.Limits = [1 100];
            app.FrameStep.Value = 1;
            app.FILEOptionsPanel_2 = uipanel(app.UIFigure);
            app.FILEOptionsPanel_2.Title = 'FILE Options';
            app.LOADRightCamera = uibutton(app.FILEOptionsPanel_2, 'push');
            app.LOADRightCamera.ButtonPushedFcn = createCallbackFcn(app, @LOADRightCameraButtonPushed, true);
            app.LOADRightCamera.Enable = 'off';
            app.LOADRightCamera.Text = 'LOAD RIGHT Camera';
            app.LOADLEFTCamera = uibutton(app.FILEOptionsPanel_2, 'push');
            app.LOADLEFTCamera.ButtonPushedFcn = createCallbackFcn(app, @LOADLEFTCameraButtonPushed, true);
            app.LOADLEFTCamera.Text = 'LOAD LEFT Camera';
            app.LOADPRIORCSVButton = uibutton(app.FILEOptionsPanel_2, 'push');
            app.LOADPRIORCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LOADPRIORCSVButtonPushed, true);
            app.LOADPRIORCSVButton.Enable = 'off';
            app.LOADPRIORCSVButton.Text = 'LOAD PRIOR CSV';
            app.useSingleVid = uibutton(app.FILEOptionsPanel_2, 'push');
            app.useSingleVid.ButtonPushedFcn = createCallbackFcn(app, @useSingleVidButtonPushed, true);
            app.useSingleVid.Enable = 'off';
            app.useSingleVid.Text = 'USE SINGLE VIDEO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ER_DLC_MoveCheck_Dual_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end