classdef Movement_IO_EUC_index_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RightPanel           matlab.ui.container.Panel
        SAVEVALUEButton      matlab.ui.control.Button
        CHECKVALUEButton     matlab.ui.control.Button
        FileoptionsPanel     matlab.ui.container.Panel
        ARMEFDropDown        matlab.ui.control.DropDown
        ARMEFDropDownLabel   matlab.ui.control.Label
        HANDPSDropDown       matlab.ui.control.DropDown
        HANDPSDropDownLabel  matlab.ui.control.Label
        HANDOCDropDown       matlab.ui.control.DropDown
        HANDOCDropDownLabel  matlab.ui.control.Label
        RESETButton          matlab.ui.control.Button
        EXPORTButton         matlab.ui.control.Button
        LOADButton           matlab.ui.control.Button
        MarkerInfoPanel      matlab.ui.container.Panel
        UITable              matlab.ui.control.Table
        MarkerPlotPanel      matlab.ui.container.Panel
        indexSlider          matlab.ui.control.Slider
        SliderLabel          matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        CSVfileName % Description
        CSVfileLocation % Description
        MarkerPlotData % Description
        MarkerYlabels % Description
        MarkerYindicies % Description
        curINDEX;
        curRowinds;
        curColinds;
        curXLINES;
    end
    
    methods (Access = private)
        
        function updatePLOT(app)

            plot(app.UIAxes, app.MarkerPlotData,'Color',[0 0 0 0.5])

            xlim(app.UIAxes,[1 height(app.MarkerPlotData)])

            % fix y lim
            ylim(app.UIAxes,[0 max(app.MarkerPlotData,[],'all')+5])

            % fix labels
            yticks(app.UIAxes,app.MarkerYindicies)
            yticklabels(app.UIAxes,app.MarkerYlabels)

            currentSliderPos = app.indexSlider.Value;
            xline(app.UIAxes,currentSliderPos,'-','Color','red','LineWidth',3)


            for cii = 1:6
                if cii <= 2
                    coloRR = 'g';
                elseif cii <= 4
                    coloRR = 'b';
                elseif cii <= 6
                    coloRR = 'm';
                end

                xline(app.UIAxes,app.curXLINES(cii),'-',...
                    'Color',coloRR,'LineWidth',1)
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UITable.RowName = {'HAND OC','HAND PS','ARM EF'};
            app.UITable.Data = zeros(3,3);

            app.curINDEX = 1;
            app.curRowinds = [1 1 2 2 3 3];
            app.curColinds = [1 2 1 2 1 2];
            app.curXLINES = zeros(1,6);

        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            
            app.UITable.RowName = {'HAND OC','HAND PS','ARM EF'};
            app.UITable.Data = zeros(3,3);

            app.curINDEX = 1;
            app.curRowinds = [1 1 2 2 3 3];
            app.curColinds = [1 2 1 2 1 2];
            app.curXLINES = zeros(1,6);

            % load CSV file
            [app.CSVfileName,app.CSVfileLocation] = uigetfile('*.mat');

            cd(app.CSVfileLocation)

            load(app.CSVfileName , 'outDATA')

            colNames = outDATA.Properties.VariableNames; %
            colNames2 = cellfun(@(x) split(x,'_'), colNames,...
                'UniformOutput',false);
            colNames3 = unique(cellfun(@(x) x{1}, colNames2,...
                'UniformOutput',false));
            colNames4 = colNames3(~matches(colNames3,'frames'));

            % Initialize 'euclidall' to store Euclidean distances between successive points
            euclidall = zeros(height(outDATA)-1,length(colNames4));

            % Iterate over each label and compute Euclidean distance for each frame
            for label_i = 1:length(colNames4)

                tmpLabel_x = [colNames4{label_i} , '_x'];
                tmpLabel_y = [colNames4{label_i} , '_y'];

                tmpXdata = outDATA.(tmpLabel_x);
                tmpYdata = outDATA.(tmpLabel_y);

                labelData = [tmpXdata , tmpYdata];

                for frame_i = 1:height(labelData)
                    if frame_i ~= height(labelData)
                        point1 = labelData(frame_i,:);
                        point2 = labelData(frame_i + 1,:);
                        euclidall(frame_i , label_i) = pdist2(point1 , point2);
                    end
                end
            end

            euclidalltmp = euclidall;

            mall = mean(mean(euclidalltmp));
            sall = std(std(euclidalltmp));
            artThresh = mall + (sall*2);

            euclidalltmp2 = euclidalltmp;
            euclidalltmp2(euclidalltmp > artThresh) = 0;

            rangeAll = zeros(1,width(euclidalltmp2));
            for rrii = 1:width(euclidalltmp2)
                rangeAll(rrii) = range(euclidalltmp2(:,rrii));
            end

            % Create y data matrix
            yPLOTDATA = euclidalltmp2;
            pCURRENT = 0;
            yLABELSi = zeros(width(euclidalltmp2),1);
            yLABELSn = cell(width(euclidalltmp2),1);
            for piip = 1:width(euclidalltmp2)

                if piip ~= 1
                    pBump = rangeAll(piip-1);
                    pCURRENT = pCURRENT + pBump;
                end

                tmpDATA = euclidalltmp2(:,piip) + pCURRENT;
                yPLOTDATA(:,piip) = tmpDATA;

                yLABELSi(piip) = pCURRENT;
                yLABELSn{piip} = ['Marker ', num2str(piip)];
            end

            % Store plot elements
            app.MarkerPlotData = yPLOTDATA;
            app.MarkerYlabels = yLABELSn;
            app.MarkerYindicies = yLABELSi;

            % Update GUI
            % 1. drop down menus
            app.HANDPSDropDown.Items = yLABELSn;
            app.HANDOCDropDown.Items = yLABELSn;
            app.ARMEFDropDown.Items = yLABELSn;
            % 2. Update slider
            app.indexSlider.Limits = [1 height(euclidalltmp2)];
            % app.indexSlider.MajorTicks = 1:50:height(euclidalltmp2)
            % 3. update plot
            updatePLOT(app)

            % 
            app.HANDOCDropDown.Enable = "on";
            app.HANDPSDropDown.Enable = "off";
            app.ARMEFDropDown.Enable = "off";
            app.CHECKVALUEButton.Enable = "off";
            app.SAVEVALUEButton.Enable = "off";
            app.indexSlider.Enable = "on";

        end

        % Value changing function: indexSlider
        function indexSliderValueChanging(app, event)
            changingValue = event.Value;
            app.indexSlider.Value = changingValue;
            updatePLOT(app)
        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)
            
            % Find location
            tmpLoc = app.CSVfileLocation;
            % dirParts = split(tmpLoc,'\');
            % dirParts2 = find(matches(dirParts,'mat folder'));
            % dirParts3 = dirParts(1:dirParts2-1);
            % saveLOC = join(dirParts3,'\');
            % cd(saveLOC{1})
            saveLOC = tmpLoc;
            cd(saveLOC)
            

            % Create save name
            newNAME = [app.CSVfileName, '_EUC_IndiciesNEW.xlsx'];

            % Create table
            dataParts = app.UITable.Data;
            nameParts = app.UITable.RowName;
            dataTAB = array2table(dataParts,'VariableNames',...nameParts
                {'StartInd','StopInd','Marker'});
            dataTAB.Movements = nameParts;
            finalTAB = [dataTAB(:,4) dataTAB(:,1:3)];

            % Export as xlsx
            writetable(finalTAB,newNAME)


        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.UITable.RowName = {'HAND OC','HAND PS','ARM EF'};
            app.UITable.Data = zeros(3,3);

            app.curINDEX = 1;
            app.curRowinds = [1 1 2 2 3 3];
            app.curColinds = [1 2 1 2 1 2];
            app.curXLINES = zeros(1,6);
            updatePLOT(app)
        end

        % Button pushed function: CHECKVALUEButton
        function CHECKVALUEButtonPushed(app, event)
            
            if app.curINDEX > 6
                return
            else

                app.UITable.Data(app.curRowinds(app.curINDEX),app.curColinds(app.curINDEX)) =...
                    round(app.indexSlider.Value);
                switch app.curRowinds(app.curINDEX)
                    case 1
                        app.UITable.Data(app.curRowinds(app.curINDEX),3) =...
                            str2double(extractAfter(app.HANDOCDropDown.Value,' '));
                    case 2
                        app.UITable.Data(app.curRowinds(app.curINDEX),3) =...
                            str2double(extractAfter(app.HANDPSDropDown.Value,' '));
                    case 3
                        app.UITable.Data(app.curRowinds(app.curINDEX),3) =...
                            str2double(extractAfter(app.ARMEFDropDown.Value,' '));
                end

            end

        end

        % Button pushed function: SAVEVALUEButton
        function SAVEVALUEButtonPushed(app, event)
            
            if app.curINDEX > 6
                return
            else

                % save numbers and advance table
                app.UITable.Data(app.curRowinds(app.curINDEX),app.curColinds(app.curINDEX)) =...
                    round(app.indexSlider.Value);
                switch app.curRowinds(app.curINDEX)
                    case 1
                        app.UITable.Data(app.curRowinds(app.curINDEX),3) =...
                            str2double(extractAfter(app.HANDOCDropDown.Value,' '));
                    case 2
                        app.UITable.Data(app.curRowinds(app.curINDEX),3) =...
                            str2double(extractAfter(app.HANDPSDropDown.Value,' '));
                    case 3
                        app.UITable.Data(app.curRowinds(app.curINDEX),3) =...
                            str2double(extractAfter(app.ARMEFDropDown.Value,' '));

                        app.EXPORTButton.Enable = "on";
                end

                app.curXLINES(app.curINDEX) = round(app.indexSlider.Value);
                app.curINDEX = app.curINDEX + 1;

                if app.curINDEX == 3
                    app.CHECKVALUEButton.Enable = "off";
                    app.SAVEVALUEButton.Enable = "off";
                    app.HANDPSDropDown.Enable = "on";
                end

                if app.curINDEX == 5
                    app.CHECKVALUEButton.Enable = "off";
                    app.SAVEVALUEButton.Enable = "off";
                    app.ARMEFDropDown.Enable = "on";
                end

                app.indexSlider.Value = 1;
                updatePLOT(app)

            end


        end

        % Value changed function: HANDOCDropDown
        function HANDOCDropDownValueChanged(app, event)
            
            app.CHECKVALUEButton.Enable = "on";
            app.SAVEVALUEButton.Enable = "on";
            
        end

        % Value changed function: HANDPSDropDown
        function HANDPSDropDownValueChanged(app, event)

            app.CHECKVALUEButton.Enable = "on";
            app.SAVEVALUEButton.Enable = "on";
            
        end

        % Value changed function: ARMEFDropDown
        function ARMEFDropDownValueChanged(app, event)

            app.CHECKVALUEButton.Enable = "on";
            app.SAVEVALUEButton.Enable = "on";
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RightPanel = uipanel(app.UIFigure);
            app.MarkerPlotPanel = uipanel(app.RightPanel);
            app.MarkerPlotPanel.Title = 'Marker Plot';
            app.UIAxes = uiaxes(app.MarkerPlotPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SliderLabel = uilabel(app.MarkerPlotPanel);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Text = 'Slider';
            app.indexSlider = uislider(app.MarkerPlotPanel);
            app.indexSlider.Limits = [1 100];
            app.indexSlider.ValueChangingFcn = createCallbackFcn(app, @indexSliderValueChanging, true);
            app.indexSlider.Enable = 'off';
            app.indexSlider.Value = 1;
            app.MarkerInfoPanel = uipanel(app.RightPanel);
            app.MarkerInfoPanel.Title = 'Marker Info';
            app.UITable = uitable(app.MarkerInfoPanel);
            app.UITable.ColumnName = {'Start Index'; 'Stop Index'; 'Best Marker'; ''};
            app.UITable.RowName = {};
            app.FileoptionsPanel = uipanel(app.RightPanel);
            app.FileoptionsPanel.Title = 'File options';
            app.LOADButton = uibutton(app.FileoptionsPanel, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.EXPORTButton = uibutton(app.FileoptionsPanel, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Enable = 'off';
            app.EXPORTButton.Text = 'EXPORT';
            app.RESETButton = uibutton(app.FileoptionsPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.HANDOCDropDownLabel = uilabel(app.FileoptionsPanel);
            app.HANDOCDropDownLabel.Text = 'HAND OC';
            app.HANDOCDropDown = uidropdown(app.FileoptionsPanel);
            app.HANDOCDropDown.ValueChangedFcn = createCallbackFcn(app, @HANDOCDropDownValueChanged, true);
            app.HANDOCDropDown.Enable = 'off';
            app.HANDPSDropDownLabel = uilabel(app.FileoptionsPanel);
            app.HANDPSDropDownLabel.Text = 'HAND PS';
            app.HANDPSDropDown = uidropdown(app.FileoptionsPanel);
            app.HANDPSDropDown.ValueChangedFcn = createCallbackFcn(app, @HANDPSDropDownValueChanged, true);
            app.HANDPSDropDown.Enable = 'off';
            app.ARMEFDropDownLabel = uilabel(app.FileoptionsPanel);
            app.ARMEFDropDownLabel.Text = 'ARM EF';
            app.ARMEFDropDown = uidropdown(app.FileoptionsPanel);
            app.ARMEFDropDown.ValueChangedFcn = createCallbackFcn(app, @ARMEFDropDownValueChanged, true);
            app.ARMEFDropDown.Enable = 'off';
            app.CHECKVALUEButton = uibutton(app.RightPanel, 'push');
            app.CHECKVALUEButton.ButtonPushedFcn = createCallbackFcn(app, @CHECKVALUEButtonPushed, true);
            app.CHECKVALUEButton.Enable = 'off';
            app.CHECKVALUEButton.Text = 'CHECK VALUE';
            app.SAVEVALUEButton = uibutton(app.RightPanel, 'push');
            app.SAVEVALUEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEVALUEButtonPushed, true);
            app.SAVEVALUEButton.Enable = 'off';
            app.SAVEVALUEButton.Text = 'SAVE VALUE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Movement_IO_EUC_index_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end