classdef ruby_er_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        ARMEFDropDown        matlab.ui.control.DropDown
        ARMEFDropDownLabel   matlab.ui.control.Label
        HANDPSDropDown       matlab.ui.control.DropDown
        HANDPSDropDownLabel  matlab.ui.control.Label
        HANDOCDropDown       matlab.ui.control.DropDown
        HANDOCDropDownLabel  matlab.ui.control.Label
        LOADButton           matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        MarkerInfoPanel      matlab.ui.container.Panel
        UITable              matlab.ui.control.Table
        MarkerPlotPanel      matlab.ui.container.Panel
        indexSlider          matlab.ui.control.Slider
        SliderLabel          matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        x % Description
        y
        CSVfileName % Description
        CSVfileLocation % Description
        MarkerPlotData % Description
        MarkerYlabels % Description
        MarkerYindicies % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            

            eventname = event.EventName;
            switch eventname
                case 'WindowMouseMotion'
                    % Obtain Coordinates of mouse for each of the 4 axes

                    xlim(app.UIAxes,[1 10])
                    ylim(app.UIAxes,[1 10])
                    currentPoint1 = app.UIAxes.CurrentPoint(1,1:3);
                    x1 = currentPoint1(1);
                    y1 = currentPoint1(2);
                    app.x = currentPoint1(1);
                    app.y = currentPoint1(2);

                    % Change pointer to crosshair when within axes.
                    if (app.UIAxes.XLim(1)<x1)&&(x1<app.UIAxes.XLim(2)) && (app.UIAxes.YLim(1)<y1)&&(y1<app.UIAxes.YLim(2))
                        set(app.UIFigure,'Pointer','crosshair');
                        cla(app.UIAxes)
                        xline(app.UIAxes,x1,'-','Color','k')
                        title(app.UIAxes,[num2str(app.x) ' ' num2str(app.y)])
                    else
                        set(app.UIFigure,'Pointer','arrow'); % When outside of axes, return pointer to arrow
                    end


                case 'WindowMousePress'
                    % When mouse is pressed, get coordinates

            end


        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            

            useKEY = event.key;
            switch useKEY
                case 'a'
                    % apply numbers to table

                case 's'
                    % save numbers and advance table


            end


        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            

            % load CSV file
            [app.CSVfileName,app.CSVfileLocation] = uigetfile('*.mat');

            cd(app.CSVfileLocation)

            load(app.CSVfileName , 'outDATA')

            colNames = outDATA.Properties.VariableNames; %
            colNames2 = cellfun(@(x) split(x,'_'), colNames,...
                'UniformOutput',false);
            colNames3 = unique(cellfun(@(x) x{1}, colNames2,...
                'UniformOutput',false));
            colNames4 = colNames3(~matches(colNames3,'frames'));

            % Initialize 'euclidall' to store Euclidean distances between successive points
            euclidall = zeros(height(outDATA)-1,length(colNames4));

            % Iterate over each label and compute Euclidean distance for each frame
            for label_i = 1:length(colNames4)

                tmpLabel_x = [colNames4{label_i} , '_x'];
                tmpLabel_y = [colNames4{label_i} , '_y'];

                tmpXdata = outDATA.(tmpLabel_x);
                tmpYdata = outDATA.(tmpLabel_y);

                labelData = [tmpXdata , tmpYdata];

                for frame_i = 1:height(labelData)
                    if frame_i ~= height(labelData)
                        point1 = labelData(frame_i,:);
                        point2 = labelData(frame_i + 1,:);
                        euclidall(frame_i , label_i) = pdist2(point1 , point2);
                    end
                end
            end

            euclidalltmp = euclidall;

            mall = mean(mean(euclidalltmp));
            sall = std(std(euclidalltmp));
            artThresh = mall + (sall*2);

            euclidalltmp2 = euclidalltmp;
            euclidalltmp2(euclidalltmp > artThresh) = 0;

            rangeAll = zeros(1,width(euclidalltmp2));
            for rrii = 1:width(euclidalltmp2)
                rangeAll(rrii) = range(euclidalltmp2(:,rrii));
            end

            % Create y data matrix
            yPLOTDATA = euclidalltmp2;
            pCURRENT = 0;
            yLABELSi = zeros(width(euclidalltmp2),1);
            yLABELSn = cell(width(euclidalltmp2),1);
            for piip = 1:width(euclidalltmp2)

                if piip ~= 1
                    pBump = rangeAll(piip-1);
                    pCURRENT = pCURRENT + pBump;
                end

                tmpDATA = euclidalltmp2(:,piip) + pCURRENT;
                yPLOTDATA(:,piip) = tmpDATA;

                yLABELSi(piip) = pCURRENT;
                yLABELSn{piip} = ['Marker ', num2str(piip)];
            end

            % Store plot elements
            app.MarkerPlotData = yPLOTDATA;
            app.MarkerYlabels = yLABELSn;
            app.MarkerYindicies = yLABELSi;

            % Update GUI
            % 1. drop down menus
            app.HANDPSDropDown.Items = yLABELSn;
            app.HANDOCDropDown.Items = yLABELSn;
            app.ARMEFDropDown.Items = yLABELSn;
            % 2. Update slider
            app.indexSlider.Limits = [1 height(euclidalltmp2)];
            app.indexSlider.MajorTicks = 1:50:height(euclidalltmp2)
            % 3. update plot


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {544, 544};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {95, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LOADButton = uibutton(app.LeftPanel, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.HANDOCDropDownLabel = uilabel(app.LeftPanel);
            app.HANDOCDropDownLabel.Text = 'HAND OC';
            app.HANDOCDropDown = uidropdown(app.LeftPanel);
            app.HANDPSDropDownLabel = uilabel(app.LeftPanel);
            app.HANDPSDropDownLabel.Text = 'HAND PS';
            app.HANDPSDropDown = uidropdown(app.LeftPanel);
            app.ARMEFDropDownLabel = uilabel(app.LeftPanel);
            app.ARMEFDropDownLabel.Text = 'ARM EF';
            app.ARMEFDropDown = uidropdown(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.MarkerPlotPanel = uipanel(app.RightPanel);
            app.MarkerPlotPanel.Title = 'Marker Plot';
            app.UIAxes = uiaxes(app.MarkerPlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.MarkerPlotPanel);
            app.SliderLabel.Text = 'Slider';
            app.indexSlider = uislider(app.MarkerPlotPanel);
            app.indexSlider.Limits = [1 100];
            app.indexSlider.Value = 1;
            app.MarkerInfoPanel = uipanel(app.RightPanel);
            app.MarkerInfoPanel.Title = 'Marker Info';
            app.UITable = uitable(app.MarkerInfoPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ruby_er_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end