classdef SegPointDefine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Spinner             matlab.ui.control.Spinner
        SpinnerLabel        matlab.ui.control.Label
        nextButton          matlab.ui.control.Button
        backButton          matlab.ui.control.Button
        AddPointButton      matlab.ui.control.Button
        DisplayVideoButton  matlab.ui.control.Button
        LoadVideoButton     matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tab_vid % Description
        frameNUM % Description
        allpoints
    end
    
    methods (Access = private)
        
        function updateImage(app)
            imshow(app.tab_vid(app.frameNUM).cdata,'Parent',app.UIAxes)
            title(app.UIAxes,[num2str(app.frameNUM) ' out of ' num2str(size(app.tab_vid,2))])
            firstIND = find(~isnan(app.allpoints(:,1)),1,'last');
            xline(app.UIAxes,app.allpoints(firstIND,1),'-k')
            yline(app.UIAxes,app.allpoints(firstIND,2),'-k')
            app.Spinner.Value = app.frameNUM;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
            
            % pcNAME = getenv('COMPUTERNAME');
            % 
            % switch pcNAME
            %     case 'jst'
            %         mainDIR = 'D:\Dropbox\erin_clinic_video\Clin_2023-09-12_session1_soffoffLi_Lcam-emr-2023-09-16\videos';
            %         % Tablet video
            %     case'DESKTOP-FAGRV5G'
            %         mainDIR = 'E:\Dropbox\erin_clinic_video\Clin_2023-09-12_session1_soffoffLi_Lcam-emr-2023-09-16\videos';
            % end

            [tab_vidfile , tab_vidLoc] = uigetfile('*.mp4');

            % tab_vidLoc = [mainDIR, filesep , 'TabletVideo'];
            
            cd(tab_vidLoc)

            % tab_vidObj = VideoReader('20230912_idea08_session001_rightCam-0000.mp4');
            tab_vidObj = VideoReader(tab_vidfile);
            disp('Video read!')
            app.tab_vid = struct('cdata',zeros(tab_vidObj.Height,tab_vidObj.Width,3,'uint8'),'colormap',[]);

            frami = 1;
            while hasFrame(tab_vidObj)
                app.tab_vid(frami).cdata = readFrame(tab_vidObj);
                frami = frami+1;
                disp(num2str(frami))
            end
            disp('Video1 done!')

            app.frameNUM = 1;
            app.allpoints = nan(size(app.tab_vid,2),2);


        end

        % Button pushed function: DisplayVideoButton
        function DisplayVideoButtonPushed(app, event)
            imshow(app.tab_vid(app.frameNUM).cdata,'Parent',app.UIAxes)
            % roi = drawpoint;
            % promptPoint = roi.Position;
            % allpoints(tpI,:) = promptPoint;
            title(app.UIAxes,[num2str(app.frameNUM) ' out of ' num2str(size(app.tab_vid,2))])
        end

        % Button pushed function: AddPointButton
        function AddPointButtonPushed(app, event)
            roi = drawpoint('Parent',app.UIAxes);
            promptPoint = roi.Position;
            app.allpoints(app.frameNUM,:) = promptPoint;
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            app.frameNUM = app.frameNUM - 1;
            if app.frameNUM < 1 
                app.frameNUM = 1;
            end
            updateImage(app)

        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            app.frameNUM = app.frameNUM + 1;
            if app.frameNUM > size(app.tab_vid,2)
                app.frameNUM = size(app.tab_vid,2);
            end
            updateImage(app)

        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            if value > size(app.tab_vid,2)
                app.Spinner.Value = size(app.tab_vid,2);
            end

            if value < 1
                app.Spinner.Value = 1;
            end

            app.frameNUM = app.Spinner.Value;

            updateImage(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.DisplayVideoButton = uibutton(app.UIFigure, 'push');
            app.DisplayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayVideoButtonPushed, true);
            app.DisplayVideoButton.Text = 'Display Video';
            app.AddPointButton = uibutton(app.UIFigure, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointButtonPushed, true);
            app.AddPointButton.Text = 'Add Point';
            app.backButton = uibutton(app.UIFigure, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = '<<<<<<<<';
            app.nextButton = uibutton(app.UIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = '>>>>>>>>';
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegPointDefine

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end