classdef Seg_Mask_Review < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Slider       matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        Button       matlab.ui.control.Button
        Panel        matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
        BlueMat      matlab.ui.control.UIAxes
        GreenMat     matlab.ui.control.UIAxes
        RedMat       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        maskS % Description
        frameNUM
    end
    
    methods (Access = private)
        
        function updateVidImages(app)

            imshow(app.maskS(app.frameNUM).cdata(:,:,1),'Parent',app.RedMat)
            imshow(app.maskS(app.frameNUM).cdata(:,:,2),'Parent',app.GreenMat)
            imshow(app.maskS(app.frameNUM).cdata(:,:,3),'Parent',app.BlueMat)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            pcNAME = getenv('COMPUTERNAME');

            switch pcNAME
                case 'DESKTOP-I5CPDO7'
                    mainDIR = 'D:\Dropbox\erin_clinic_video\Clin_2023-09-12_session1_soffoffLi_Lcam-emr-2023-09-16\videos';
                case'DESKTOP-FAGRV5G'
                    mainDIR = 'E:\Dropbox\erin_clinic_video\Clin_2023-09-12_session1_soffoffLi_Lcam-emr-2023-09-16\videos';
            end
            tab_vidLoc = [mainDIR, filesep , 'TabletVideo'];
            cd(tab_vidLoc)

            % tab_vidObj = VideoReader('20230912_idea08_session001_rightCam-0000.mp4');
            % app.tab_vid = struct('cdata',zeros(tab_vidObj.Height,tab_vidObj.Width,3,'uint8'),'colormap',[]);

            % frami = 1;
            % while hasFrame(tab_vidObj)
            %     app.tab_vid(frami).cdata = readFrame(tab_vidObj);
            %     frami = frami+1;
            % end
            % disp('Video1 done!')
            load('SegMasks.mat','tab_vidMasks');
            app.maskS = tab_vidMasks;

            isEMPTYf = zeros(size(app.maskS,2),1,'logical');
            for i = 1:size(app.maskS,2)
                tmpCd = app.maskS(i).cdata;
                if isempty(tmpCd)
                    continue
                else
                    isEMPTYf(i) = true;
                end
            end
            app.maskS = app.maskS(isEMPTYf);
            app.frameNUM = 1;
            updateVidImages(app)
            % app.allpoints = nan(size(app.tab_vid,2),2);

            app.Slider.Limits = [1 size(app.maskS,2)];

        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.frameNUM = round(changingValue);
            updateVidImages(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.RedMat = uiaxes(app.GridLayout);
            app.RedMat.XTick = [];
            app.RedMat.YTick = [];
            app.GreenMat = uiaxes(app.GridLayout);
            app.GreenMat.XTick = [];
            app.GreenMat.YTick = [];
            app.BlueMat = uiaxes(app.GridLayout);
            app.BlueMat.XTick = [];
            app.BlueMat.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'fileImage_SegAPP.png');
            app.Button.Text = '';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Seg_Mask_Review

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end