classdef ClusterReviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CLUSTERReviewerUIFigure   matlab.ui.Figure
        FileOptionsMenu           matlab.ui.container.Menu
        SetDirectory              matlab.ui.container.Menu
        AdvancedOptionsMenu       matlab.ui.container.Menu
        SetMinThresh              matlab.ui.container.Menu
        ClearPlots                matlab.ui.container.Menu
        ReopenClusterbuttonMenu   matlab.ui.container.Menu
        ProgramMenu               matlab.ui.container.Menu
        ExitProgramMenu           matlab.ui.container.Menu
        RestartProgramMenu        matlab.ui.container.Menu
        FILESList                 matlab.ui.control.ListBox
        FILESListBoxLabel         matlab.ui.control.Label
        DepthnameLabel            matlab.ui.control.Label
        FileNameLabel             matlab.ui.control.Label
        ElecLabel                 matlab.ui.control.Label
        CLUSTERCOMPLETEPanel      matlab.ui.container.Panel
        ToExport                  matlab.ui.control.Switch
        ReadytoexportSwitchLabel  matlab.ui.control.Label
        ExportCluster             matlab.ui.control.Button
        ClusterProcessPanel       matlab.ui.container.Panel
        ClusterOptionGrid         matlab.ui.container.GridLayout
        FUTUREButton              matlab.ui.control.Button
        reCluster                 matlab.ui.control.Button
        TEMPLATEButton            matlab.ui.control.Button
        DELETEButton              matlab.ui.control.Button
        COMBINEButton             matlab.ui.control.Button
        ClusterSelectPanel        matlab.ui.container.Panel
        GridLayout6               matlab.ui.container.GridLayout
        CB_6                      matlab.ui.control.StateButton
        CB_3                      matlab.ui.control.StateButton
        CB_5                      matlab.ui.control.StateButton
        CB_4                      matlab.ui.control.StateButton
        CB_2                      matlab.ui.control.StateButton
        CB_1                      matlab.ui.control.StateButton
        EPOCHViewerPanel          matlab.ui.container.Panel
        MaxEpochsEditField        matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel   matlab.ui.control.Label
        InSecondsLabel            matlab.ui.control.Label
        EpochDur                  matlab.ui.control.Spinner
        EpochDurSpinnerLabel      matlab.ui.control.Label
        EpochSpinner              matlab.ui.control.Spinner
        EpochSpinnerLabel         matlab.ui.control.Label
        forwardEpoch              matlab.ui.control.Button
        backwardEpoch             matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        AllWaveformsTab           matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        allCLUSTS                 matlab.ui.control.UIAxes
        ClusteredWaveformsTab     matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        SC6                       matlab.ui.control.UIAxes
        SC5                       matlab.ui.control.UIAxes
        SC4                       matlab.ui.control.UIAxes
        SC3                       matlab.ui.control.UIAxes
        SC2                       matlab.ui.control.UIAxes
        SC1                       matlab.ui.control.UIAxes
        ClusteredOverlayTab       matlab.ui.container.Tab
        allCLUSTSover             matlab.ui.control.UIAxes
        ClusterMetricsTab         matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        GridLayout5_2             matlab.ui.container.GridLayout
        Button4_2                 matlab.ui.control.Button
        Button3_2                 matlab.ui.control.Button
        Button2_2                 matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        GridLayout5               matlab.ui.container.GridLayout
        Button4                   matlab.ui.control.Button
        Button3                   matlab.ui.control.Button
        Button2                   matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        allCLUSTSover_8           matlab.ui.control.UIAxes
        allCLUSTSover_7           matlab.ui.control.UIAxes
        allCLUSTSover_5           matlab.ui.control.UIAxes
        allCLUSTSover_3           matlab.ui.control.UIAxes
        ThresholdSettingsPanel    matlab.ui.container.Panel
        NoiseManVal               matlab.ui.control.NumericEditField
        NoiseSDSlider             matlab.ui.control.Slider
        NoiseSDSliderLabel        matlab.ui.control.Label
        ResetThresh               matlab.ui.control.Button
        NegativeThresh            matlab.ui.control.StateButton
        TheshManVal               matlab.ui.control.NumericEditField
        ThreshSDSlider            matlab.ui.control.Slider
        ThreshSDSliderLabel       matlab.ui.control.Label
        ProgramInformationPanel   matlab.ui.container.Panel
        InfoLabel                 matlab.ui.control.Label
        TimeSeriesDataPanel       matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        SpikeLocPlot              matlab.ui.control.UIAxes
        VoltRawPlot               matlab.ui.control.UIAxes
        ClusterOperationsPanel    matlab.ui.container.Panel
        SKIP_e                    matlab.ui.control.Button
        ClusterRun                matlab.ui.control.Button
        ThresholdRun              matlab.ui.control.Button
        LoadCase                  matlab.ui.control.Button
    end

    % TO DO
    % 4/21/2023
    % 8/4/2023
    % 11/21/2023
    % ------ Time line and spike viewer
    %%%% 1. Create button to show waveforms in epoch view overlaid over all
    %%%% waveforms - white overlaid on turquoise


    properties (Access = private)
        rawDatFold % Folder location where mat files with DLC data are stored
        spkCluFold % Folder location where spike times will be saved
        depFile2load % File name that will be loaded
        DepthID % Name of depth from file to be loaded
        allSPIKEdata % Spike data pulled from loaded file
        spikeDataRaw % CSPK raw voltage data
        spkFS % Spike sampling frequency
        startT % Spike recording time
        Elnum % electrode number
        waveForms % Waveform data
        minSPdist
        peakIndex % Index sample locations for threshold crossings
        XLIMa % X limit of waveform based on sample number
        fileNumber % Integer for the array ID for current file
        totalSecs % total duration of session

        threshUsed % Threshold used for crossing
        kmeansNUsed % K number of clusters
        spkData % spike data used for clustering
        voltThresh % Voltage threshold
        minSepDist % minimum distance used between spikes
        clusterMetrics % Cluster metrics
        waveFORMS % store all waveforms
        spikeTSindex % store integer sample index

        allClusterNum % Number of clusters
        clusterIDactive % Active cluster ID
        allClusterID % Cluster ID
        waveFeatures % Waveform features
        pnFlag2
        numEpochs % Number of Epochs
        EpochInds % Indicies of epoch starts and stops
        CurEpoch % Current epoch
        waveColors % colors for waveforms

        elecFieldCur % current row for electrode fields
        elecFieldTotal % total number of electrode fields
        elecCurName % name of current electrode field
        elecAllName % all names of electrodes
        newClustStValue

    end
    
    methods (Access = private)
        
        function plotRastWaves(app, stage, sub)
            
            switch stage
                
                case 'new'
                    
                    cla(app.allCLUSTS)
                    app.allCLUSTS.Title = [];
                    
                case 'threshold'
                    
                    cla(app.allCLUSTS)
                    
                    app.allCLUSTS.Title = [];
                    
                    twaveForms = app.waveForms.allWaves.waves;
                    totLen = app.XLIMa;
                    
                    hold(app.allCLUSTS, 'on')
                    
                    if size(twaveForms,2) > 200
                        rwInd = randi(size(twaveForms,2),1,200);
                    else
                        rwInd = 1:size(twaveForms,2);
                    end
                    
                    twaves2plot = twaveForms(:,rwInd);

                    plot(app.allCLUSTS,twaves2plot,'Color',[72/255 202/255 202/255 0.25],'LineWidth',1)
   
                    xlim(app.allCLUSTS,[1 totLen]);
                    
                    if isempty(twaveForms)
                        ylim(app.allCLUSTS,[-1500 1500])
                    else
                        ylim(app.allCLUSTS,[min(twaves2plot(:)) max(twaves2plot(:))]);
                    end
                    
                    app.allCLUSTS.XTick = [];
                    app.allCLUSTS.YTick = [];
                    titleStr = ['All waveforms = ' , num2str(size(twaveForms,2))];
                    title(app.allCLUSTS,titleStr);

                    % JUST ADDED

                    updateWaveTSplot(app)
                    
                case '3'

                    % Set up data to plot
                    tnumClusts = app.allClusterNum; % Single value with number of clusters
                    cluID = app.clusterIDactive; % Index vector (length of samples) with cluster IDs
                    cfilt = app.allClusterID; % Vector with actual cluster IDs
                    twaveForms = app.waveForms.allWaves.waves; % Waveforms
                    
                    switch sub
                        case 'cluster'
                            messageUPDATE(app, [0 0.6 0], 'Clusters Done!')
                        case 'temp-sing'
                            
                            % Set up data to plot
                            tnumClusts = app.allClusterNum;
                            cluID = app.clusterIDactive;
                            cfilt = app.allClusterID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Templates Done!')
                            
                        case 'combine-clus'
                            messageUPDATE(app, [0 0.6 0], 'Clusters Combined!')
                        case 'delete-clus'
                            messageUPDATE(app, [0 0.6 0], 'Clusters Deleted!')
                        case 'clust-sing'
                            messageUPDATE(app, [0 0.6 0], 'Re-Clustered!')
                            
                    end

                    % MODIFY COLORS
                    
                    cla(app.allCLUSTSover)

                    meanWave = zeros(height(app.waveForms.allWaves.waves),tnumClusts);
                    for i = 1:tnumClusts
                        plotID = ['SC',num2str(i)];
                        clustTMP = cluID(i);
                        wavLocs = cfilt == clustTMP;
                        wavTmp = twaveForms(:,wavLocs);

                        fRate = round(sum(wavLocs)/app.totalSecs,2);

                        if size(wavTmp,2) > 50
                            randSEL = randperm(size(wavTmp,2),50);
                            wavTmp2u = wavTmp(:,randSEL);
                        else
                            wavTmp2u = wavTmp;
                        end

                        plot(app.(plotID), wavTmp2u,'Color',[app.waveColors(i,:) 0.5])

                        xlim(app.(plotID),[1 57])

                        title(app.(plotID),['Cl ', num2str(i), ' = ',...
                            num2str(size(wavTmp,2)),...
                            ' | FR = ' , num2str(fRate),'Hz'],'Color',[0.2 0.2 0.2],...
                            'FontSize',9);

                        meanWave(:,i) = mean(wavTmp2u,2);

                        hold(app.allCLUSTSover,'on')
                        plot(app.allCLUSTSover,meanWave(:,i),'Color',app.waveColors(i,:),...
                            'LineWidth',2.5)
                        hold(app.allCLUSTSover,'off')
                        xlim(app.allCLUSTSover,[1 57])
                    end
                    
            end
        end
        
        function clearWavePlots(app , stage)

            switch stage
                case 1 % just threshold

                    cla(app.allCLUSTS)
                    app.allCLUSTS.Title = [];

                case 2 % just clusters

                    cla(app.allCLUSTSover)
                    for ci = 1:6
                        tmpClustIa = ['SC' , num2str(ci)];
                        cla(app.(tmpClustIa))
                        app.(tmpClustIa).Title = [];
                    end
                    app.allCLUSTSover.Title = [];

                case 3 % Everything

                    cla(app.allCLUSTSover)
                    cla(app.allCLUSTS)
                    for ci = 1:6
                        tmpClustIa = ['SC' , num2str(ci)];
                        cla(app.(tmpClustIa))
                        app.(tmpClustIa).Title = [];
                    end
                    app.allCLUSTS.Title = [];
                    app.allCLUSTSover.Title = [];
            end

        end
        
        
        function messageUPDATE(app, coLOR, mesG)
            
            app.InfoLabel.Text = mesG;
            app.InfoLabel.FontColor = coLOR;
            
        end
        
        function updateThreshold(app , stage)

            sampData = app.spikeDataRaw;
            tspkData = double(sampData);
            sampLen = round(app.spkFS/1000);
            
            app.InfoLabel.Text = 'Estimating Threshold...';
            app.InfoLabel.FontColor = 'r';
            
            thresh = (round(std(double(sampData)) * app.TheshManVal.Value) + mean(double(sampData)));
            noise = (round(std(double(sampData)) * app.NoiseManVal.Value) + mean(double(sampData)));
            minDist = round(app.spkFS/1000) * app.minSPdist; % was 1.5
            
            tic;
            
            %%% Check threshold
            
            threshFlag = app.NegativeThresh.Value;
            if threshFlag == 0
                app.pnFlag2 = 1;
            else
                app.pnFlag2 = 0;
            end
            
            %%%%%%%%%%%%%%%%%%%%
            
            [ waveData ] = extractWaveforms_Clz_v07(tspkData, thresh, noise, minDist, app.spkFS, app.pnFlag2); %%%%%%%%%%%%%%%%%%%%% PNFLAG
            app.waveForms = waveData;
            
            app.InfoLabel.Text = 'Waves Extracted...';
            app.InfoLabel.FontColor = 'b';
            
            app.peakIndex = waveData.allWavesInfo.alllocs;
            
            totLen = length(1 - (sampLen/2):1 + (round(sampLen*0.8)-1));
            app.XLIMa = totLen;
            
            timeDone = toc;
            
            app.threshUsed = app.TheshManVal.Value;
            app.kmeansNUsed = 4;
            app.spkData = tspkData;
            app.voltThresh = thresh;
            app.minSepDist = minDist;
            
            % plotRastWaves(app, 'threshold')
            
            app.InfoLabel.Text = ['Thresholding Done!' , '  time elapsed: ', num2str(round(timeDone)), ' s'] ;
            app.InfoLabel.FontColor = [0 0.4980 0];
            
            %%% Following Thresholding
            % 1. Make Clustering available
            % 2. Manual Thresholing available
            % 3. Abort analysis on bad cell
            
            % Initiate buttons
            app.ThresholdRun.Enable = 'off';
            app.ClusterRun.Enable = 'on';
            app.SKIP_e.Enable = "on";

            if stage == 1

                % Indicate threshold in window
                app.ThresholdSettingsPanel.Enable = 'on';
                app.TheshManVal.Value = 4;
                app.ThreshSDSlider.Value = app.TheshManVal.Value;
                app.ThreshSDSlider.Enable = 'on';

            end

            clearWavePlots(app,1);

            plotRastWaves(app, 'new')
            plotRastWaves(app, 'threshold')

            
        end
        
        function updateTimeSeriesPlot(app)

            % Need to output EPOCH, YLIM and XLIM values

            % Input app.CurEpoch, ylim , app.EpochInds
            % updateTimeSeriesPlot(app, ylim2use)

            meanYlim = mean(double(app.spikeDataRaw));
            stdYlim = std(double(app.spikeDataRaw));
            uppYlim = meanYlim + (stdYlim*7); % MAKE AN INPUT for STD
            dwnYlim = meanYlim - (stdYlim*7);

            ylim(app.VoltRawPlot,[dwnYlim uppYlim])

            spkDat2plot = app.spikeDataRaw(app.EpochInds(app.CurEpoch,1):...
                app.EpochInds(app.CurEpoch,2));
            colORvolt = [0.9412 0.9412 0.9412 0.8];

            plot(app.VoltRawPlot,spkDat2plot,'Color',colORvolt);
            drawnow;

            % Set xlim for all
            epochLength = length(app.EpochInds(app.CurEpoch,1):app.EpochInds(app.CurEpoch,2));
            xlim(app.VoltRawPlot,[1 epochLength])

        end
        
        function updateWaveTSplot(app)


            %%% NEED TO GET YLIM from VOLT PLOT

            % Get current epoch
            epochID = app.CurEpoch;
            % Extract voltage information from full recording
            filrawDat = double(app.spikeDataRaw(app.EpochInds(epochID,1):app.EpochInds(epochID,2)));

            % Obtain all wave peak indices
            allWaveINDS = app.waveForms.allWavesInfo.alllocs;

            % Offset epoch block with 50 samples on either end
            % Identify peak indices that fall within epoch range
            epochWaveInd = allWaveINDS >= app.EpochInds(epochID,1) + 50 &...
                allWaveINDS <= app.EpochInds(epochID,2) - 50;

            % CLUSTER INFO
            % app.allClusterNum
            % app.allClusterID

            if app.allClusterNum > 1
                clusterIDS = app.allClusterID;
                epochClusters = clusterIDS(epochWaveInd);
                epochNumSwitch = 2;
            else
                clusterIDS = ones(length(epochWaveInd),1);
                epochClusters = ones(sum(epochWaveInd),1);
                epochNumSwitch = 1;
            end

            uniClustIds = unique(epochClusters);

            cla(app.SpikeLocPlot)
            %%%%%%%%%%%% THIS IS NEW 09/29/2023
            for cci = 1:length(unique(epochClusters))


                findClustID = uniClustIds(cci);
                % CHECK THIS WITH 1
                epochClust2use = epochWaveInd & transpose((clusterIDS == findClustID)); 

                % Create index of those peak index rows
                % epochWaveVales = find(epochWaveInd);
                %%%%%% REPACE epochWaveInd WITH epochClust2use
                epochPeakInds = allWaveINDS(epochClust2use);
                epochPeakValues = app.spikeDataRaw(epochPeakInds);

                % Obtain all Waves and all positions
                allWAVES = app.waveForms.allWaves.waves;
                allPOSITIONS = app.waveForms.allWaves.positions;
                epochStart = app.EpochInds(epochID,1);

                % Choose to which to plot pos or neg
                % Default to positive plot

                xlim(app.SpikeLocPlot,[1 length(filrawDat)])
                hold(app.SpikeLocPlot,"on")

                % For waveforms
                tmpXwaveR1 = allPOSITIONS(:,epochClust2use);
                tmpXwaveR2 = reshape(tmpXwaveR1,numel(tmpXwaveR1),1);
                tmpXwaveR3 = (tmpXwaveR2 - epochStart) + 1;
                % tmpXwave = (tmpXwaveR2 - epochStart) + 1;
                tmpXwave = 1:numel(filrawDat);

                tmpYwaveR = allWAVES(:,epochClust2use);
                tmpYwave = reshape(tmpYwaveR,numel(tmpYwaveR),1);

                tmpXwaveNan = nan(1,numel(filrawDat));
                tmpYwaveNan = tmpXwaveNan;
                tmpYwaveNan(tmpXwaveR3) = tmpYwave;

                switch epochNumSwitch
                    case 1
                        plot(app.SpikeLocPlot,tmpXwave,tmpYwaveNan,...
                            'LineWidth',0.5,"Color",'k')
                    case 2
                        plot(app.SpikeLocPlot,tmpXwave,tmpYwaveNan,...
                            'LineWidth',1,"Color",app.waveColors(cci,:))
                end

                % For peak points
                tmpXwave_ppR = epochPeakInds;
                tmpXwave_pp = (tmpXwave_ppR - epochStart) + 1;
                tmpYwave_pp = epochPeakValues;

                switch epochNumSwitch
                    case 1
                        plot(app.SpikeLocPlot,tmpXwave_pp,tmpYwave_pp,...
                            '.',"Color",'r')
                    case 2
                        plot(app.SpikeLocPlot,tmpXwave_pp,tmpYwave_pp,...
                            '.',"Color",'k')
                end

                hold(app.SpikeLocPlot,"off")

            end
            
        end


        function resetCbuttons(app)

            for ci = 1:6
                app.(['CB_',num2str(ci)]).Value = 0;
            end

            app.CB_1ValueChanged
            app.CB_2ValueChanged
            app.CB_3ValueChanged
            app.CB_4ValueChanged
            app.CB_5ValueChanged
            app.CB_6ValueChanged

            for ci2 = 1:6
                app.(['CB_',num2str(ci2)]).Enable = "off";
            end

            for ci3 = 1:app.allClusterNum
                app.(['CB_',num2str(ci3)]).Enable = "on";
            end

        end


        function loadNEXTfile(app)

app.EPOCHViewerPanel.Enable = "off";

            allLIST2 = app.FILESList.Items;
            app.fileNumber = app.fileNumber + 1;

            app.depFile2load = allLIST2{app.fileNumber};

            % Get case name
            firstPart = extractAfter(app.depFile2load,10);
            app.DepthID = extractBefore(firstPart,'F');

            % Load file
            cd(app.rawDatFold)
            load(app.depFile2load);

            app.allSPIKEdata = ProcEphys.Spike;

            elecFields = fieldnames(app.allSPIKEdata);
            app.elecFieldCur = 1;
            app.elecFieldTotal = numel(elecFields);
            app.elecCurName = elecFields{1};
            app.elecAllName = elecFields;
            app.newClustStValue = 10001;

            app.Elnum = elecFields{1}(2);

            app.spikeDataRaw = app.allSPIKEdata.(elecFields{1}).rawData;
            app.spkFS = app.allSPIKEdata.(elecFields{1}).Hz*1000;
            app.startT = app.allSPIKEdata.(elecFields{1}).startTime;

            % UpdateINFO
            app.FileNameLabel.Text = app.depFile2load;
            app.DepthnameLabel.Text = app.DepthID;
            app.ElecLabel.Text = elecFields{1};

            app.InfoLabel.Text = 'Loading files...';
            app.InfoLabel.FontColor = 'r';
            drawnow;

            app.InfoLabel.Text = 'File Loaded!!';
            app.InfoLabel.FontColor = [0 0.4980 0];
            drawnow;

            % Create timeseries and Epoch
            secEpoch = app.EpochDur.Value; % in seconds

            app.numEpochs = floor(width(app.spikeDataRaw)/(app.spkFS*secEpoch));
            app.EpochSpinner.Limits = [1 app.numEpochs];
            % app.maxEp.Value = app.numEpochs;  %%%%%%%%%%%%%%% CONSIDER ADDING
            startINDS = transpose(1:app.spkFS*secEpoch:(app.spkFS*app.numEpochs*secEpoch));
            stopINDS = [startINDS(2:end)-1 ; width(app.spikeDataRaw)];
            app.EpochInds = [startINDS , stopINDS];
            app.CurEpoch = 1;
            app.MaxEpochsEditField.Value = app.numEpochs;
            app.totalSecs = width(app.spikeDataRaw)/app.spkFS;

            % FUNCTION TO prep timeseries epoch
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            clearTSplots(app , 1)
            updateTimeSeriesPlot(app)

            plotRastWaves(app, 'new')

            % INITIATE buttons
            app.ThresholdRun.Enable = 'on';
            app.LoadCase.Enable = 'off';

            % TURN OFF Cluster STUFF
            app.ClusterSelectPanel.Enable = "off";
            app.ClusterProcessPanel.Enable = "off";

            % SET CLUSTER FIELDS TO empty / 0
            app.allClusterNum = 0;
            app.clusterIDactive = nan;
            app.allClusterID = 0;
            app.waveFeatures = nan;

            % Clear plots
            % clearTSplots(app , 1);
            clearWavePlots(app , 3);

            % RESET BUTTONS
            resetCbuttons(app)

            % SET Ready to export to NO
            app.ToExport.Value = "No";
            app.ExportCluster.Enable = "off";

            % CLEAR PLOTS
            % clearTSplots(app , 1)

            % CLEAR CLUSTER PLOTS
            clearWavePlots(app , 3)

            % UPDATE file number and highlight

            curFlistStyle = uistyle(BackgroundColor="yellow");
            addStyle(app.FILESList,curFlistStyle,"item",app.fileNumber)

        end
        
        function clearTSplots(app , clearState)

            switch clearState
                case 1
                    cla(app.SpikeLocPlot)
                    cla(app.VoltRawPlot)
                    cla(app.allCLUSTS)
                case 2
                    cla(app.SpikeLocPlot)
                case 3
                    cla(app.VoltRawPlot)
            end

        end
        
        function loadNEXTelectrode(app)


    app.EPOCHViewerPanel.Enable = "off";

            app.newClustStValue = 10001;

            app.Elnum = app.elecCurName(2);

            app.spikeDataRaw = app.allSPIKEdata.(app.elecCurName).rawData;
            app.spkFS = app.allSPIKEdata.(app.elecCurName).Hz*1000;
            app.startT = app.allSPIKEdata.(app.elecCurName).startTime;

            % UpdateINFO
            app.ElecLabel.Text = app.elecCurName;

            app.InfoLabel.Text = 'Loading files...';
            app.InfoLabel.FontColor = 'r';
            drawnow;

            app.InfoLabel.Text = 'File Loaded!!';
            app.InfoLabel.FontColor = [0 0.4980 0];
            drawnow;

            % Create timeseries and Epoch
            secEpoch = app.EpochDur.Value; % in seconds

            app.numEpochs = floor(width(app.spikeDataRaw)/(app.spkFS*secEpoch));
            app.EpochSpinner.Limits = [1 app.numEpochs];
            % app.maxEp.Value = app.numEpochs;  %%%%%%%%%%%%%%% CONSIDER ADDING
            startINDS = transpose(1:app.spkFS*secEpoch:(app.spkFS*app.numEpochs*secEpoch));
            stopINDS = [startINDS(2:end)-1 ; width(app.spikeDataRaw)];
            app.EpochInds = [startINDS , stopINDS];
            app.CurEpoch = 1;
            app.MaxEpochsEditField.Value = app.numEpochs;
            app.totalSecs = width(app.spikeDataRaw)/app.spkFS;

            % FUNCTION TO prep timeseries epoch
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            clearTSplots(app , 1)
            updateTimeSeriesPlot(app)

            plotRastWaves(app, 'new')
            drawnow;

            % INITIATE buttons
            app.ThresholdRun.Enable = 'on';
            app.LoadCase.Enable = 'off';

            % TURN OFF Cluster STUFF
            app.ClusterSelectPanel.Enable = "off";
            app.ClusterProcessPanel.Enable = "off";

            % SET CLUSTER FIELDS TO empty / 0
            app.allClusterNum = 0;
            app.clusterIDactive = nan;
            app.allClusterID = 0;
            app.waveFeatures = nan;

            % RESET BUTTONS
            resetCbuttons(app)

            % CLEAR CLUSTER PLOTS
            clearWavePlots(app , 3)

            % SET Ready to export to NO
            app.ToExport.Value = "No";
            app.ExportCluster.Enable = "off";


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ThresholdSettingsPanel.Enable = 'off';
            app.minSPdist = 1.5;
            app.allCLUSTSover.Color = [0.2 0.2 0.2];
            app.allCLUSTS.Color = [0.2 0.2 0.2];
            app.VoltRawPlot.Color = [0.2 0.2 0.2];
            for ci = 1:6
                tmpClustIa = ['SC' , num2str(ci)];
                app.(tmpClustIa).Color = [0.2 0.2 0.2];
            end

            RGB_w_Colors = [246, 189, 96  ;...
                            247, 237, 226 ;...
                            245, 202, 195 ;...
                            189, 184, 176 ;...
                            132, 165, 157 ;...
                            242, 132, 130];
            app.waveColors = RGB_w_Colors/255;

        end

        % Button pushed function: LoadCase
        function LoadCasePushed(app, event)
            
            % ADD to FILE LIST - BUT LOCK down for now
            % CREATE timeseries plot - with EPOCH length ADD SPINNER to set
            % block duration 

            % CHECK HOW THIS WORKS! WITH COMPLETED FILES

            % CD to Raw data
            cd(app.rawDatFold)

            % Get list
            % allLIST1 = dir('*.mat');
            % allLIST2 = {allLIST1.name};
            % app.FILESList.Items = allLIST2;

            fileLoadt = true;
            filCkCount = 1;
            while fileLoadt

                app.depFile2load = app.FILESList.Items{filCkCount};

                % Get case name
                firstPart = extractAfter(app.depFile2load,10);
                app.DepthID = extractBefore(firstPart,'F');

                % Look for field that indicates it has already been sorted
                tmpMatInfo = matfile(app.depFile2load );
                varlist = who(tmpMatInfo);

                if matches('sortedDone',varlist)

                    filCkCount = filCkCount + 1;

                else
                    app.depFile2load = app.FILESList.Items{filCkCount};
                    fileLoadt = false;
                end

            end

            app.fileNumber = find(matches(app.FILESList.Items, app.depFile2load));

            % Load file
            cd(app.rawDatFold)
            load(app.depFile2load);

            app.allSPIKEdata = ProcEphys.Spike;

            elecFields = fieldnames(app.allSPIKEdata);
            app.elecFieldCur = 1;
            app.elecFieldTotal = numel(elecFields);
            app.elecCurName = elecFields{1};
            app.elecAllName = elecFields;

            app.Elnum = elecFields{1}(2);

            app.spikeDataRaw = app.allSPIKEdata.(elecFields{1}).rawData;
            app.spkFS = app.allSPIKEdata.(elecFields{1}).Hz*1000;
            app.startT = app.allSPIKEdata.(elecFields{1}).startTime;
            app.newClustStValue = 10001;

            % UpdateINFO
            app.FileNameLabel.Text = app.depFile2load;
            app.DepthnameLabel.Text = app.DepthID;
            app.ElecLabel.Text = elecFields{1};

            app.InfoLabel.Text = 'Loading files...';
            app.InfoLabel.FontColor = 'r';
            drawnow;

            app.InfoLabel.Text = 'File Loaded!!';
            app.InfoLabel.FontColor = [0 0.4980 0];
            drawnow;

            % Create timeseries and Epoch
            secEpoch = app.EpochDur.Value; % in seconds

            app.numEpochs = floor(width(app.spikeDataRaw)/(app.spkFS*secEpoch));
            app.EpochSpinner.Limits = [1 app.numEpochs];
            % app.maxEp.Value = app.numEpochs;  %%%%%%%%%%%%%%% CONSIDER ADDING
            startINDS = transpose(1:app.spkFS*secEpoch:(app.spkFS*app.numEpochs*secEpoch));
            stopINDS = [startINDS(2:end)-1 ; width(app.spikeDataRaw)];
            app.EpochInds = [startINDS , stopINDS];
            app.CurEpoch = 1;
            app.MaxEpochsEditField.Value = app.numEpochs;
            app.totalSecs = width(app.spikeDataRaw)/app.spkFS;

            % FUNCTION TO prep timeseries epoch
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            updateTimeSeriesPlot(app)

            plotRastWaves(app, 'new')

            % INITIATE buttons
            app.ThresholdRun.Enable = 'on';
            app.LoadCase.Enable = 'off';

            curFlistStyle = uistyle(BackgroundColor="yellow");
            addStyle(app.FILESList,curFlistStyle,"item",app.fileNumber)


        end

        % Button pushed function: ThresholdRun
        function ThresholdRunButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % SECOND STEP OF APP
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % THRESHOLD TRACE
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            updateThreshold(app , 1)

            updateWaveTSplot(app)

            app.EPOCHViewerPanel.Enable = "on";

            app.ThresholdSettingsPanel.Enable = "on";

        end

        % Menu selected function: SetMinThresh
        function SetMinThreshMenuSelected(app, event)
            prompt = {'Min Distance btwn spikes (ms)'};
            dlg_title = 'Min Dist.';
            num_lines = [1 35];
            defaultans = {num2str(app.minSPdist)};
            answer = inputdlg(prompt,dlg_title,num_lines,defaultans);

            app.minSPdist = str2double(answer{1});
        end

        % Menu selected function: ExitProgramMenu
        function ExitProgramMenuSelected(app, event)
            delete(app)
        end

        % Menu selected function: RestartProgramMenu
        function RestartProgramMenuSelected(app, event)
            delete(app)
            ClusterReviewer()
        end

        % Button pushed function: ClusterRun
        function ClusterRunButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % THIRD STEP OF APP
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % CLUSTER WAVEFORMS
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % messageUPDATE(app, [1 0 0], 'Clustering...')
            
            twaveForms = app.waveForms.allWaves.waves;
            
            if size(twaveForms,2) >= 6
                
                [ xFeats ] = createSpkFeatures( twaveForms,  app.spkFS  );
                
                [idxF,Cen] = kmeans(xFeats, app.kmeansNUsed,'Distance','cityblock',...
                    'Replicates',6);
                
                [ nidx , app.clusterMetrics , ~] = spkClusterQual( xFeats, idxF, Cen, 2 );
                
                tnumClusts = numel(unique(nidx));
                app.allClusterNum = tnumClusts;
                
                cluID = unique(nidx);
                app.clusterIDactive = cluID;
                
                cfilt = nidx;
                app.allClusterID = cfilt;
                app.waveFeatures = xFeats;
                app.waveFORMS = app.waveForms.allWaves;
                app.spikeTSindex = app.waveForms.allWavesInfo.alllocs;
            else
                app.allClusterNum = 1;
                app.clusterIDactive = 1;
                app.allClusterID = 1;
                app.waveFeatures = nan;
                app.spikeTSindex = nan;
            end
            
            cla(app.allCLUSTSover);
            
            clearWavePlots(app,2)
            
            plotRastWaves(app, '3', 'cluster')
            
            messageUPDATE(app, [0 0.4980 0], 'Clusters Found!')
            
            %%% Following Thresholding
            % 1. Make Combine available
            % 2. Make Delete available
            % 3. Make Template available
            % 4. Close cluster
            
            % INITIATE BUTTONS
            
            % EXPORT CLUSTER BUTTON - turn on
            % app.expClus.Enable = 'on';
            app.ThresholdRun.Enable = 'off';
            app.ClusterRun.Enable = 'off';
            app.SKIP_e.Enable = 'off';
            % CLUSTER OPTION PANEL - turn on

            % ACTIVATE CLUSTER PANEL

            app.ClusterSelectPanel.Enable = "on";
            app.ClusterProcessPanel.Enable = "on";

            % UPDATE CLUSTER BUTTON

            resetCbuttons(app)

            % TURN OFF THRESHOLD

            app.ThresholdSettingsPanel.Enable = "off";

            % TURN ON EXPORT

            app.CLUSTERCOMPLETEPanel.Enable = "on";

            % CLEAR TS plots
            clearTSplots(app , 2)

            % UPDATE TS plot
            updateWaveTSplot(app)


        end

        % Value changed function: ThreshSDSlider
        function ThreshSDSliderValueChanged(app, event)
            
            app.ClusterRun.Enable = 'on';
            app.SKIP_e.Enable = 'on';
            %%%%%%%%%%%% TURN OFF EXPORT CLUSTER BUTTON
            % app.expClus.Enable = 'off';
            app.TheshManVal.Value = app.ThreshSDSlider.Value;

            updateThreshold(app , 2)
            
        end

        % Menu selected function: SetDirectory
        function SetDirectoryMenuSelected(app, event)
            
            
            % message = ["LOAD Folder","Select Data folder that contains Processed mat files."];
            % uialert(app.CLUSTERReviewerUIFigure,message,"LOAD directory", ...
            %     "Icon","info");
            

            curDir = pwd;
            tmpRAWdata = uigetdir(curDir,'CHOOSE DATA DIRECTORY');

            app.rawDatFold =  tmpRAWdata;
            % wildPAT = wildcardPattern("Except","\");
            % tmppat = "\" + wildPAT + textBoundary;
            % mainPath = extractBefore(app.rawDatFold,tmppat);

            % message = ["SAVE DIRETORY","Select directory to create SAVE folder."];
            % uialert(app.CLUSTERReviewerUIFigure,message,"SAVE directory", ...
            %     "Icon","info");

            % saveRAWdata = uigetdir(curDir,'CHOOSE SAVE DIRECTORY');

            app.spkCluFold =  [app.rawDatFold , filesep , 'ClusteredSpikeTimes', filesep];

            if ~exist(app.spkCluFold,'dir')
                mkdir(app.spkCluFold)
            end

            % CD to Raw data
            cd(app.rawDatFold)

            % Get list
            allLIST1 = dir('*.mat');
            allLIST2 = {allLIST1.name};
            app.FILESList.Items = allLIST2;

            % MAY CHANGE IN THE FUTURE
            app.depFile2load = app.FILESList.Items{1};
            % Get case name
            firstPart = extractAfter(app.depFile2load,10);
            app.DepthID = extractBefore(firstPart,'F');

            app.FileNameLabel.Text = app.depFile2load;
            app.DepthnameLabel.Text = app.DepthID;

            app.LoadCase.Enable = 'on';
        end

        % Button pushed function: backwardEpoch
        function backwardEpochButtonPushed(app, event)
            % Do something if hit end
            if app.CurEpoch == 1
                app.EpochSpinner.Value = 1;
                return
            else
                app.CurEpoch = app.CurEpoch - 1;
                app.EpochSpinner.Value = app.CurEpoch;
            end

            updateTimeSeriesPlot(app)
            updateWaveTSplot(app)

            % updateNLXPLOT(app)
            % Calculate waveform features for first block
            % calculateWaves(app)
            % Plot waveforms
            % updateWAVEplot(app)
        end

        % Button pushed function: forwardEpoch
        function forwardEpochButtonPushed(app, event)
            % Update epoch spinner

            % Do something if hit end
            if app.CurEpoch == app.numEpochs
                app.EpochSpinner.Value = app.numEpochs;
                return
            else
                app.CurEpoch = app.CurEpoch + 1;
                app.EpochSpinner.Value = app.CurEpoch;
            end

            updateTimeSeriesPlot(app)
            updateWaveTSplot(app)
        end

        % Value changed function: EpochDur
        function EpochDurValueChanged(app, event)

            % Create timeseries and Epoch
            secEpoch = app.EpochDur.Value; % in seconds

            app.numEpochs = floor(width(app.spikeDataRaw)/(app.spkFS*secEpoch));

            if app.numEpochs > 1

                app.EpochSpinner.Limits = [1 app.numEpochs];
                startINDS = transpose(1:app.spkFS*secEpoch:(app.spkFS*app.numEpochs*secEpoch));
                stopINDS = [startINDS(2:end)-1 ; width(app.spikeDataRaw)];
                app.EpochInds = [startINDS , stopINDS];
                app.CurEpoch = 1;
                app.EpochSpinner.Value = 1;

            else
                epochDurList = app.EpochDur.Limits(1):...
                               app.EpochDur.Step:...
                               app.EpochDur.Limits(2);

                currentDUR = find(secEpoch == epochDurList);
                
                app.EpochDur.Value = epochDurList(currentDUR - 1);

                secEpoch2 = app.EpochDur.Value;
                app.numEpochs = floor(width(app.spikeDataRaw)/(app.spkFS*secEpoch2));

            end

            % Need to update max epoch field
            app.MaxEpochsEditField.Value = app.numEpochs;


        end

        % Value changed function: CB_1
        function CB_1ValueChanged(app, event)

            if app.CB_1.Value == 0
                app.CB_1.BackgroundColor = [0 0 0];
            else
                app.CB_1.BackgroundColor = [0 1 0];
            end
            
        end

        % Value changed function: CB_2
        function CB_2ValueChanged(app, event)
            if app.CB_2.Value == 0
                app.CB_2.BackgroundColor = [0 0 0];
            else
                app.CB_2.BackgroundColor = [0 1 0];
            end
            
        end

        % Value changed function: CB_4
        function CB_4ValueChanged(app, event)
            if app.CB_4.Value == 0
                app.CB_4.BackgroundColor = [0 0 0];
            else
                app.CB_4.BackgroundColor = [0 1 0];
            end
        end

        % Value changed function: CB_5
        function CB_5ValueChanged(app, event)
            if app.CB_5.Value == 0
                app.CB_5.BackgroundColor = [0 0 0];
            else
                app.CB_5.BackgroundColor = [0 1 0];
            end
            
        end

        % Value changed function: CB_3
        function CB_3ValueChanged(app, event)
            if app.CB_3.Value == 0
                app.CB_3.BackgroundColor = [0 0 0];
            else
                app.CB_3.BackgroundColor = [0 1 0];
            end
            
        end

        % Value changed function: CB_6
        function CB_6ValueChanged(app, event)
            if app.CB_6.Value == 0
                app.CB_6.BackgroundColor = [0 0 0];
            else
                app.CB_6.BackgroundColor = [0 1 0];
            end
            
        end

        % Value changed function: NoiseSDSlider
        function NoiseSDSliderValueChanged(app, event)
            app.ClusterRun.Enable = 'on';
            app.SKIP_e.Enable = 'on';
            %%%%%%%%%%%% TURN OFF EXPORT CLUSTER BUTTON
            % app.expClus.Enable = 'off';
            app.NoiseManVal.Value = app.NoiseSDSlider.Value;

            updateThreshold(app , 2)
            
        end

        % Button pushed function: COMBINEButton
        function COMBINEButtonPushed(app, event)
            

            % Find selected clusters
            combClustInd = zeros(app.allClusterNum,1,'logical');
            for ci = 1:app.allClusterNum
                tmpVal = app.(['CB_',num2str(ci)]).Value;
                combClustInd(ci) = tmpVal;
            end

            if sum(combClustInd) >= 2
                combClIDs = app.clusterIDactive(combClustInd);
                combClLocs = ismember(app.allClusterID,combClIDs);
                tmpAllclusts = app.allClusterID;
                tmpAllclusts(combClLocs) = app.newClustStValue;

                tmpNewCids = app.clusterIDactive(~combClustInd);
                tmpNewCids = [tmpNewCids ; app.newClustStValue];

                app.allClusterID = tmpAllclusts;
                app.newClustStValue = app.newClustStValue + 1;

                % Overwrite cluster NUM, cluster ID
                app.allClusterNum = numel(tmpNewCids);
                app.clusterIDactive = tmpNewCids;

                cla(app.allCLUSTSover);
                clearWavePlots(app,3)
                plotRastWaves(app, '3', 'combine-clus')
                messageUPDATE(app, [0 0.4980 0], 'Clusters COMBINED!')

            end

            resetCbuttons(app)
            updateWaveTSplot(app)

        end

        % Value changed function: ToExport
        function ToExportValueChanged(app, event)
            
            if matches(app.ToExport.Value,"Yes")
                app.ExportCluster.Enable = "on";
            else
                app.ExportCluster.Enable = "off";
            end
            
        end

        % Button pushed function: ExportCluster
        function ExportClusterButtonPushed(app, event)
            

            exportDLG = uiprogressdlg(app.CLUSTERReviewerUIFigure,'Title','EXPORTING CLUSTER FILE',...
                'Indeterminate','on');
            drawnow


            % CD to raw data folder
            cd(app.spkCluFold)
            % Create save name 
            % ADD - ElectrodeID
            % ADD - SpCl
            eleID = app.elecCurName;
            fSaveName = ['SpCl_',eleID,'_',extractAfter(app.depFile2load,'_')];

            % Spike IDS
            spikeClInfo.clusterIDS = app.allClusterID;
            % Spike Wavefeatures
            spikeClInfo.waveFeatures = app.waveFeatures;
            % Spike WaveForms
            spikeClInfo.waveForms = app.waveFORMS;
            % Spike thresholds
            spikeClInfo.APthreshold = app.TheshManVal.Value; 
            % Spike time index
            spikeClInfo.SpikeTSindex = transpose(app.spikeTSindex);
            % Spike time AO start
            spikeClInfo.AOstartTS = app.startT;

            % Compute cluster centroids
            centroids = zeros(numel(unique(spikeClInfo.clusterIDS)),...
                width(spikeClInfo.waveFeatures));  % idx by feature
            tmpClustIDS = unique(spikeClInfo.clusterIDS);

            for ci = 1:numel(tmpClustIDS)
                tmpFEATS = spikeClInfo.waveFeatures(spikeClInfo.clusterIDS == tmpClustIDS(ci),:);
                tmpMean = mean(tmpFEATS);
                centroids(ci,:) = tmpMean;
            end

            [ ~ , finalclusterMetrics , ~] =...
                spkClusterQual( spikeClInfo.waveFeatures, spikeClInfo.clusterIDS,...
                centroids, 1);

            % Spike Cluster metrics
            spikeClInfo.SpikeClusterMetrics = finalclusterMetrics;

            % Save data
            save(fSaveName , "spikeClInfo")

            % TURN off 
            app.ToExport.Value = 'No';
            app.ExportCluster.Enable = 'off';

            % CD back to RAW folder
            cd(app.rawDatFold)

            % Check for next Electrode
            if app.elecFieldCur == app.elecFieldTotal
                uialert(app.CLUSTERReviewerUIFigure,...
                    'All Electrodes Done! Moving to Next File',...
                    'File Message','Icon','success')

                sortedDone = nan;
                % SAVE complete flag in the file
                save(app.depFile2load , 'sortedDone' , '-append')

                % LOAD NEXT FILE FUNCTION
                loadNEXTfile(app)


            else
                app.elecFieldCur = app.elecFieldCur + 1;
                app.elecCurName = app.elecAllName{app.elecFieldCur};

                % LOAD NEXT ELECTRODE FUNCTION
                uialert(app.CLUSTERReviewerUIFigure,...
                    'Electrode Done! Moving to Next Electrode',...
                    'File Message','Icon','success')

                % LOAD NEXT ELECTRODE FUNCTION
                loadNEXTelectrode(app)


            end

            close(exportDLG)


        end

        % Button pushed function: DELETEButton
        function DELETEButtonPushed(app, event)
            
            delClustInd = zeros(app.allClusterNum,1,'logical');
            for ci = 1:app.allClusterNum
                tmpVal = app.(['CB_',num2str(ci)]).Value;
                delClustInd(ci) = tmpVal;
            end

            if sum(delClustInd) ~= 0
                delClIDs = app.clusterIDactive(delClustInd);
                delClLocs = ismember(app.allClusterID,delClIDs);
                tmpAllclusts = app.allClusterID;
                tmpAllclusts(delClLocs) = 99999999;

                %%%% UPDATE these FOR other processes

                tmpNewCids = app.clusterIDactive(~delClustInd);
                app.allClusterID = tmpAllclusts;

                % Overwrite cluster NUM, cluster ID
                app.allClusterNum = numel(tmpNewCids);
                app.clusterIDactive = tmpNewCids;
                clearWavePlots(app,3)
                plotRastWaves(app, '3', 'delete-clus')
            end

            resetCbuttons(app)
            updateWaveTSplot(app)


        end

        % Button pushed function: reCluster
        function reClusterButtonPushed(app, event)
            
            tmpCluInds =  app.allClusterID;
            Fs = app.spkFS;
            allwaves = app.waveForms.allWaves.waves;
            
            reClustInd = zeros(app.allClusterNum,1,'logical');
            for ci = 1:app.allClusterNum
                tmpVal = app.(['CB_',num2str(ci)]).Value;
                reClustInd(ci) = tmpVal;
            end
            reClIDs = app.clusterIDactive(reClustInd);

            if sum(reClustInd) ~= 0

            % CHECK THE NEW VALUE USED
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            [ newIndex , exceedflag , app.newClustStValue] = clusterSingle_v3( allwaves,...
                tmpCluInds, reClIDs, Fs, app.newClustStValue);

            % [ newIndex , exceedflag ] = clusterSingle_v2( allwaves,...
            %     tmpCluInds, reClIDs, Fs);

                if ~exceedflag

                    app.allClusterID = newIndex;
                    tmpNewCids = unique(newIndex(newIndex ~= 99999999));
                    app.allClusterNum = numel(tmpNewCids);
                    app.clusterIDactive = tmpNewCids;

                    clearWavePlots(app,3)
                    plotRastWaves(app, '3', 'clust-sing')

                    messageUPDATE(app, [0 0.4980 0], 'Re-Clustered!')

                    resetCbuttons(app)

                else

                    uialert('Too many clusters')

                end
            end
            updateWaveTSplot(app)
        end

        % Button pushed function: TEMPLATEButton
        function TEMPLATEButtonPushed(app, event)
            

            % Available Cluster IDS
            % actClus = app.clusterIDactive;
            % Get Cluster ID to change
            tmClustInd = zeros(app.allClusterNum,1,'logical');
            for ci = 1:app.allClusterNum
                tmpVal = app.(['CB_',num2str(ci)]).Value;
                tmClustInd(ci) = tmpVal;
            end
            tmClIDs = app.clusterIDactive(tmClustInd);
            inds2refine = tmClIDs;
            num2refine = numel(inds2refine);

            if sum(tmClustInd) ~= 0

                for cci = 1:num2refine
                    curIndsT = find(app.allClusterID == inds2refine(cci));

                    % Actual waves
                    actwaves = app.waveForms.allWaves.waves(:,app.allClusterID == inds2refine(cci));
                    messageUPDATE(app, 'r', 'Template Matching...')
                    newcluInds = app.allClusterID;

                    [corInds] = computeASCI_Clz_v05(actwaves); % was v2
                    keepInds = curIndsT(corInds);
                    excluInds = curIndsT(~(corInds));

                    newcluInds(keepInds) = inds2refine(cci);
                    nActClu = app.newClustStValue;
                    app.newClustStValue = app.newClustStValue + 1;
                    newcluInds(excluInds) = nActClu;
                    app.allClusterID = newcluInds;

                    tmpNewCids = unique(app.allClusterID(app.allClusterID ~= 99999999));
                    app.allClusterNum = numel(tmpNewCids);
                    app.clusterIDactive = tmpNewCids;

                    % Overwrite DATA
                    % Cluster IDS
                    % CHECK cluster NUMBER
                    if app.allClusterNum > 6

                        clearWavePlots(app,3)
                        plotRastWaves(app, '3', 'temp-sing')
                        uialert('Too many clusters')

                        break

                    else
                        clearWavePlots(app,3)
                        plotRastWaves(app, '3', 'temp-sing')
                        messageUPDATE(app, [0 0.4980 0], 'Template Refined!')
                        resetCbuttons(app)
                    end

                end
            end
            updateWaveTSplot(app)


        end

        % Menu selected function: ClearPlots
        function ClearPlotsMenuSelected(app, event)
            

            clearWavePlots(app,3)
            clearTSplots(app , 1)

            % INITIATE buttons - CONSIDER A SWITCH
            app.ThresholdRun.Enable = 'off';
            app.LoadCase.Enable = 'on';
            app.ClusterRun.Enable = 'off';
            app.SKIP_e.Enable = 'off';
            app.ThresholdSettingsPanel.Enable = 'off';

            % TURN OFF Cluster STUFF
            app.ClusterSelectPanel.Enable = "off";
            app.ClusterProcessPanel.Enable = "off";

            % SET CLUSTER FIELDS TO empty / 0
            app.allClusterNum = 0;
            app.clusterIDactive = nan;
            app.allClusterID = 0;
            app.waveFeatures = nan;

            % RESET BUTTONS
            resetCbuttons(app)

            % SET Ready to export to NO
            app.ToExport.Value = "No";
            app.ExportCluster.Enable = "off";


        end

        % Value changed function: NegativeThresh
        function NegativeThreshValueChanged(app, event)
            updateThreshold(app , 2)
        end

        % Button pushed function: ResetThresh
        function ResetThreshButtonPushed(app, event)
            

            app.ThresholdRun.Enable = 'on';


        end

        % Menu selected function: ReopenClusterbuttonMenu
        function ReopenClusterbuttonMenuSelected(app, event)
             app.ClusterRun.Enable = 'on';
             app.SKIP_e.Enable = 'on';
        end

        % Button pushed function: SKIP_e
        function SKIP_eButtonPushed(app, event)
            
            % Add Proc Done flag
            % Do not save anything in Clustered folder
            % Reset GUI
            % Move to next file

            % TURN off
            app.ToExport.Value = 'No';
            app.ExportCluster.Enable = 'off';

            % Check for next Electrode
            if app.elecFieldCur == app.elecFieldTotal
                uialert(app.CLUSTERReviewerUIFigure,...
                    'All Electrodes Done! Moving to Next File',...
                    'File Message','Icon','success')

                sortedDone = nan;
                % SAVE complete flag in the file
                save(app.depFile2load , 'sortedDone' , '-append')

                % LOAD NEXT FILE FUNCTION
                loadNEXTfile(app)


            else
                app.elecFieldCur = app.elecFieldCur + 1;
                app.elecCurName = app.elecAllName{app.elecFieldCur};

                % LOAD NEXT ELECTRODE FUNCTION
                uialert(app.CLUSTERReviewerUIFigure,...
                    'Electrode Done! Moving to Next Electrode',...
                    'File Message','Icon','success')

                % LOAD NEXT ELECTRODE FUNCTION
                loadNEXTelectrode(app)


            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CLUSTERReviewerUIFigure = uifigure('Visible', 'off');
            app.CLUSTERReviewerUIFigure.Name = 'CLUSTER Reviewer';
            app.CLUSTERReviewerUIFigure.Icon = fullfile(pathToMLAPP, 'appImage.jpg');
            app.FileOptionsMenu = uimenu(app.CLUSTERReviewerUIFigure);
            app.FileOptionsMenu.Text = 'File Options';
            app.SetDirectory = uimenu(app.FileOptionsMenu);
            app.SetDirectory.MenuSelectedFcn = createCallbackFcn(app, @SetDirectoryMenuSelected, true);
            app.SetDirectory.Accelerator = 'D';
            app.SetDirectory.Text = 'Set &Directory';
            app.AdvancedOptionsMenu = uimenu(app.CLUSTERReviewerUIFigure);
            app.AdvancedOptionsMenu.Text = 'Advanced Options';
            app.SetMinThresh = uimenu(app.AdvancedOptionsMenu);
            app.SetMinThresh.MenuSelectedFcn = createCallbackFcn(app, @SetMinThreshMenuSelected, true);
            app.SetMinThresh.Text = 'Minimum Distance btw spikes';
            app.ClearPlots = uimenu(app.AdvancedOptionsMenu);
            app.ClearPlots.MenuSelectedFcn = createCallbackFcn(app, @ClearPlotsMenuSelected, true);
            app.ClearPlots.Text = 'Clear Plots and Panels';
            app.ReopenClusterbuttonMenu = uimenu(app.AdvancedOptionsMenu);
            app.ReopenClusterbuttonMenu.MenuSelectedFcn = createCallbackFcn(app, @ReopenClusterbuttonMenuSelected, true);
            app.ReopenClusterbuttonMenu.Text = 'Reopen Cluster button';
            app.ProgramMenu = uimenu(app.CLUSTERReviewerUIFigure);
            app.ProgramMenu.Text = 'Program';
            app.ExitProgramMenu = uimenu(app.ProgramMenu);
            app.ExitProgramMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitProgramMenuSelected, true);
            app.ExitProgramMenu.Text = 'Exit Program';
            app.RestartProgramMenu = uimenu(app.ProgramMenu);
            app.RestartProgramMenu.MenuSelectedFcn = createCallbackFcn(app, @RestartProgramMenuSelected, true);
            app.RestartProgramMenu.Text = 'Restart Program';
            app.ClusterOperationsPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.ClusterOperationsPanel.Title = 'Cluster Operations';
            app.LoadCase = uibutton(app.ClusterOperationsPanel, 'push');
            app.LoadCase.ButtonPushedFcn = createCallbackFcn(app, @LoadCasePushed, true);
            app.LoadCase.Icon = fullfile(pathToMLAPP, 'loadButton2.jpg');
            app.LoadCase.Enable = 'off';
            app.LoadCase.Tooltip = {'Load button - load a mat file'};
            app.LoadCase.Text = '';
            app.ThresholdRun = uibutton(app.ClusterOperationsPanel, 'push');
            app.ThresholdRun.ButtonPushedFcn = createCallbackFcn(app, @ThresholdRunButtonPushed, true);
            app.ThresholdRun.Icon = fullfile(pathToMLAPP, 'threshButton.jpg');
            app.ThresholdRun.Enable = 'off';
            app.ThresholdRun.Tooltip = {'Threshold button will use threshold to isolate spikes'};
            app.ThresholdRun.Text = '';
            app.ClusterRun = uibutton(app.ClusterOperationsPanel, 'push');
            app.ClusterRun.ButtonPushedFcn = createCallbackFcn(app, @ClusterRunButtonPushed, true);
            app.ClusterRun.Icon = fullfile(pathToMLAPP, 'clusterButton.jpg');
            app.ClusterRun.Enable = 'off';
            app.ClusterRun.Tooltip = {'Run cluster process'};
            app.ClusterRun.Text = '';
            app.SKIP_e = uibutton(app.ClusterOperationsPanel, 'push');
            app.SKIP_e.ButtonPushedFcn = createCallbackFcn(app, @SKIP_eButtonPushed, true);
            app.SKIP_e.Enable = 'off';
            app.SKIP_e.Tooltip = {'Run cluster process'};
            app.SKIP_e.Text = {'SKIP'; 'Electrode'};
            app.TimeSeriesDataPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.TimeSeriesDataPanel.Title = 'Time Series Data';
            app.GridLayout = uigridlayout(app.TimeSeriesDataPanel);
            app.VoltRawPlot = uiaxes(app.GridLayout);
            subtitle(app.VoltRawPlot, 'Filtered Data')
            app.VoltRawPlot.XTick = [];
            app.VoltRawPlot.YTick = [];
            app.SpikeLocPlot = uiaxes(app.GridLayout);
            subtitle(app.SpikeLocPlot, 'Spike locations')
            app.SpikeLocPlot.XTick = [];
            app.SpikeLocPlot.YTick = [];
            app.ProgramInformationPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.ProgramInformationPanel.Title = 'Program Information';
            app.InfoLabel = uilabel(app.ProgramInformationPanel);
            app.InfoLabel.Text = 'Progress.....';
            app.ThresholdSettingsPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.ThresholdSettingsPanel.Title = 'Threshold Settings';
            app.ThreshSDSliderLabel = uilabel(app.ThresholdSettingsPanel);
            app.ThreshSDSliderLabel.Text = 'Thresh. SD';
            app.ThreshSDSlider = uislider(app.ThresholdSettingsPanel);
            app.ThreshSDSlider.Limits = [1 6];
            app.ThreshSDSlider.MajorTicks = [];
            app.ThreshSDSlider.ValueChangedFcn = createCallbackFcn(app, @ThreshSDSliderValueChanged, true);
            app.ThreshSDSlider.MinorTicks = [1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6];
            app.ThreshSDSlider.Value = 4;
            app.TheshManVal = uieditfield(app.ThresholdSettingsPanel, 'numeric');
            app.TheshManVal.Limits = [1 6];
            app.TheshManVal.Editable = 'off';
            app.TheshManVal.Value = 4;
            app.NegativeThresh = uibutton(app.ThresholdSettingsPanel, 'state');
            app.NegativeThresh.ValueChangedFcn = createCallbackFcn(app, @NegativeThreshValueChanged, true);
            app.NegativeThresh.Text = 'Switch to Negative';
            app.ResetThresh = uibutton(app.ThresholdSettingsPanel, 'push');
            app.ResetThresh.ButtonPushedFcn = createCallbackFcn(app, @ResetThreshButtonPushed, true);
            app.ResetThresh.Text = {'RESET'; 'Threshold'};
            app.NoiseSDSliderLabel = uilabel(app.ThresholdSettingsPanel);
            app.NoiseSDSliderLabel.Text = 'Noise SD';
            app.NoiseSDSlider = uislider(app.ThresholdSettingsPanel);
            app.NoiseSDSlider.Limits = [10 20];
            app.NoiseSDSlider.MajorTicks = [];
            app.NoiseSDSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSDSliderValueChanged, true);
            app.NoiseSDSlider.MinorTicks = [10 10.5 11 11.5 12 12.5 13 13.5 14 14.5 15 15.5 16 16.5 17 17.5 18 18.5 19 19.5 20];
            app.NoiseSDSlider.Value = 10;
            app.NoiseManVal = uieditfield(app.ThresholdSettingsPanel, 'numeric');
            app.NoiseManVal.Limits = [10 20];
            app.NoiseManVal.Editable = 'off';
            app.NoiseManVal.Value = 10;
            app.TabGroup = uitabgroup(app.CLUSTERReviewerUIFigure);
            app.AllWaveformsTab = uitab(app.TabGroup);
            app.AllWaveformsTab.Title = 'All Waveforms';
            app.GridLayout3 = uigridlayout(app.AllWaveformsTab);
            app.allCLUSTS = uiaxes(app.GridLayout3);
            app.allCLUSTS.XTick = [];
            app.allCLUSTS.YTick = [];
            app.ClusteredWaveformsTab = uitab(app.TabGroup);
            app.ClusteredWaveformsTab.Title = 'Clustered Waveforms';
            app.GridLayout2 = uigridlayout(app.ClusteredWaveformsTab);
            app.SC1 = uiaxes(app.GridLayout2);
            app.SC1.XTick = [];
            app.SC1.YTick = [];
            app.SC2 = uiaxes(app.GridLayout2);
            app.SC2.XTick = [];
            app.SC2.YTick = [];
            app.SC3 = uiaxes(app.GridLayout2);
            app.SC3.XTick = [];
            app.SC3.YTick = [];
            app.SC4 = uiaxes(app.GridLayout2);
            app.SC4.XTick = [];
            app.SC4.YTick = [];
            app.SC5 = uiaxes(app.GridLayout2);
            app.SC5.XTick = [];
            app.SC5.YTick = [];
            app.SC6 = uiaxes(app.GridLayout2);
            app.SC6.XTick = [];
            app.SC6.YTick = [];
            app.ClusteredOverlayTab = uitab(app.TabGroup);
            app.ClusteredOverlayTab.Title = 'Clustered Overlay';
            app.allCLUSTSover = uiaxes(app.ClusteredOverlayTab);
            app.allCLUSTSover.XTick = [];
            app.allCLUSTSover.YTick = [];
            app.ClusterMetricsTab = uitab(app.TabGroup);
            app.ClusterMetricsTab.Title = 'Cluster Metrics';
            app.GridLayout4 = uigridlayout(app.ClusterMetricsTab);
            app.allCLUSTSover_3 = uiaxes(app.GridLayout4);
            app.allCLUSTSover_3.XTick = [];
            app.allCLUSTSover_3.YTick = [];
            app.allCLUSTSover_5 = uiaxes(app.GridLayout4);
            app.allCLUSTSover_5.XTick = [];
            app.allCLUSTSover_5.YTick = [];
            app.allCLUSTSover_7 = uiaxes(app.GridLayout4);
            app.allCLUSTSover_7.XTick = [];
            app.allCLUSTSover_7.YTick = [];
            app.allCLUSTSover_8 = uiaxes(app.GridLayout4);
            app.allCLUSTSover_8.XTick = [];
            app.allCLUSTSover_8.YTick = [];
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.Button = uibutton(app.GridLayout5, 'push');
            app.Button2 = uibutton(app.GridLayout5, 'push');
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.GridLayout5, 'push');
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.GridLayout5, 'push');
            app.Button4.Text = 'Button4';
            app.GridLayout5_2 = uigridlayout(app.GridLayout4);
            app.Button_2 = uibutton(app.GridLayout5_2, 'push');
            app.Button2_2 = uibutton(app.GridLayout5_2, 'push');
            app.Button2_2.Text = 'Button2';
            app.Button3_2 = uibutton(app.GridLayout5_2, 'push');
            app.Button3_2.Text = 'Button3';
            app.Button4_2 = uibutton(app.GridLayout5_2, 'push');
            app.Button4_2.Text = 'Button4';
            app.EPOCHViewerPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.EPOCHViewerPanel.Enable = 'off';
            app.EPOCHViewerPanel.Title = 'EPOCH Viewer';
            app.backwardEpoch = uibutton(app.EPOCHViewerPanel, 'push');
            app.backwardEpoch.ButtonPushedFcn = createCallbackFcn(app, @backwardEpochButtonPushed, true);
            app.backwardEpoch.Icon = fullfile(pathToMLAPP, 'backButton.jpg');
            app.backwardEpoch.Text = '';
            app.forwardEpoch = uibutton(app.EPOCHViewerPanel, 'push');
            app.forwardEpoch.ButtonPushedFcn = createCallbackFcn(app, @forwardEpochButtonPushed, true);
            app.forwardEpoch.Icon = fullfile(pathToMLAPP, 'forwardButton.jpg');
            app.forwardEpoch.Text = '';
            app.EpochSpinnerLabel = uilabel(app.EPOCHViewerPanel);
            app.EpochSpinnerLabel.Enable = 'off';
            app.EpochSpinnerLabel.Text = 'Epoch#';
            app.EpochSpinner = uispinner(app.EPOCHViewerPanel);
            app.EpochSpinner.Editable = 'off';
            app.EpochSpinner.Enable = 'off';
            app.EpochDurSpinnerLabel = uilabel(app.EPOCHViewerPanel);
            app.EpochDurSpinnerLabel.Text = 'Epoch Dur';
            app.EpochDur = uispinner(app.EPOCHViewerPanel);
            app.EpochDur.Step = 5;
            app.EpochDur.Limits = [5 20];
            app.EpochDur.ValueChangedFcn = createCallbackFcn(app, @EpochDurValueChanged, true);
            app.EpochDur.Value = 5;
            app.InSecondsLabel = uilabel(app.EPOCHViewerPanel);
            app.InSecondsLabel.Text = 'In Seconds';
            app.MaxEpochsEditFieldLabel = uilabel(app.EPOCHViewerPanel);
            app.MaxEpochsEditFieldLabel.Text = 'Max Epochs';
            app.MaxEpochsEditField = uieditfield(app.EPOCHViewerPanel, 'numeric');
            app.MaxEpochsEditField.Editable = 'off';
            app.ClusterSelectPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.ClusterSelectPanel.Enable = 'off';
            app.ClusterSelectPanel.Title = 'Cluster Select';
            app.GridLayout6 = uigridlayout(app.ClusterSelectPanel);
            app.CB_1 = uibutton(app.GridLayout6, 'state');
            app.CB_1.ValueChangedFcn = createCallbackFcn(app, @CB_1ValueChanged, true);
            app.CB_1.Icon = fullfile(pathToMLAPP, 'bspike1.png');
            app.CB_1.Text = '';
            app.CB_2 = uibutton(app.GridLayout6, 'state');
            app.CB_2.ValueChangedFcn = createCallbackFcn(app, @CB_2ValueChanged, true);
            app.CB_2.Icon = fullfile(pathToMLAPP, 'bspike2.png');
            app.CB_2.Text = '';
            app.CB_4 = uibutton(app.GridLayout6, 'state');
            app.CB_4.ValueChangedFcn = createCallbackFcn(app, @CB_4ValueChanged, true);
            app.CB_4.Icon = fullfile(pathToMLAPP, 'bspike4.png');
            app.CB_4.Text = '';
            app.CB_5 = uibutton(app.GridLayout6, 'state');
            app.CB_5.ValueChangedFcn = createCallbackFcn(app, @CB_5ValueChanged, true);
            app.CB_5.Icon = fullfile(pathToMLAPP, 'bspike5.png');
            app.CB_5.Text = '';
            app.CB_3 = uibutton(app.GridLayout6, 'state');
            app.CB_3.ValueChangedFcn = createCallbackFcn(app, @CB_3ValueChanged, true);
            app.CB_3.Icon = fullfile(pathToMLAPP, 'bspike3.png');
            app.CB_3.Text = '';
            app.CB_6 = uibutton(app.GridLayout6, 'state');
            app.CB_6.ValueChangedFcn = createCallbackFcn(app, @CB_6ValueChanged, true);
            app.CB_6.Icon = fullfile(pathToMLAPP, 'bspike6.png');
            app.CB_6.Text = '';
            app.ClusterProcessPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.ClusterProcessPanel.Enable = 'off';
            app.ClusterProcessPanel.Title = 'Cluster Process';
            app.ClusterOptionGrid = uigridlayout(app.ClusterProcessPanel);
            app.COMBINEButton = uibutton(app.ClusterOptionGrid, 'push');
            app.COMBINEButton.ButtonPushedFcn = createCallbackFcn(app, @COMBINEButtonPushed, true);
            app.COMBINEButton.Text = 'COMBINE';
            app.DELETEButton = uibutton(app.ClusterOptionGrid, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButtonPushed, true);
            app.DELETEButton.Text = 'DELETE';
            app.TEMPLATEButton = uibutton(app.ClusterOptionGrid, 'push');
            app.TEMPLATEButton.ButtonPushedFcn = createCallbackFcn(app, @TEMPLATEButtonPushed, true);
            app.TEMPLATEButton.Text = 'TEMPLATE';
            app.reCluster = uibutton(app.ClusterOptionGrid, 'push');
            app.reCluster.ButtonPushedFcn = createCallbackFcn(app, @reClusterButtonPushed, true);
            app.reCluster.Text = 'RE-CLUSTER';
            app.FUTUREButton = uibutton(app.ClusterOptionGrid, 'push');
            app.FUTUREButton.Text = 'FUTURE';
            app.CLUSTERCOMPLETEPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.CLUSTERCOMPLETEPanel.Enable = 'off';
            app.CLUSTERCOMPLETEPanel.Title = 'CLUSTER COMPLETE';
            app.ExportCluster = uibutton(app.CLUSTERCOMPLETEPanel, 'push');
            app.ExportCluster.ButtonPushedFcn = createCallbackFcn(app, @ExportClusterButtonPushed, true);
            app.ExportCluster.Enable = 'off';
            app.ExportCluster.Text = 'EXPORT CLUSTER';
            app.ReadytoexportSwitchLabel = uilabel(app.CLUSTERCOMPLETEPanel);
            app.ReadytoexportSwitchLabel.Text = 'Ready to export';
            app.ToExport = uiswitch(app.CLUSTERCOMPLETEPanel, 'slider');
            app.ToExport.Items = {'No', 'Yes'};
            app.ToExport.ValueChangedFcn = createCallbackFcn(app, @ToExportValueChanged, true);
            app.ToExport.Value = 'No';
            app.ElecLabel = uilabel(app.CLUSTERReviewerUIFigure);
            app.ElecLabel.Text = 'Elec. #';
            app.FileNameLabel = uilabel(app.CLUSTERReviewerUIFigure);
            app.FileNameLabel.Text = 'File Name';
            app.DepthnameLabel = uilabel(app.CLUSTERReviewerUIFigure);
            app.DepthnameLabel.Text = 'Depth name';
            app.FILESListBoxLabel = uilabel(app.CLUSTERReviewerUIFigure);
            app.FILESListBoxLabel.Text = 'FILES';
            app.FILESList = uilistbox(app.CLUSTERReviewerUIFigure);
            % Show the figure after all components are created
            app.CLUSTERReviewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClusterReviewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CLUSTERReviewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CLUSTERReviewerUIFigure)
        end
    end
end