classdef SpikeQualityRView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        DirectoryMenu   matlab.ui.container.Menu
        TimelinePanel   matlab.ui.container.Panel
        GridLayout3     matlab.ui.container.GridLayout
        EpochPanel      matlab.ui.container.Panel
        GridLayout4     matlab.ui.container.GridLayout
        NUMLabel        matlab.ui.control.Label
        EPOCHLabel      matlab.ui.control.Label
        NEXTButton      matlab.ui.control.Button
        BACKButton      matlab.ui.control.Button
        UIAxes3_2       matlab.ui.control.UIAxes
        UIAxes3         matlab.ui.control.UIAxes
        Panel           matlab.ui.container.Panel
        GridLayout2     matlab.ui.container.GridLayout
        TabGroup2       matlab.ui.container.TabGroup
        FeaturesTab     matlab.ui.container.Tab
        GridLayout9     matlab.ui.container.GridLayout
        featureMap      matlab.ui.control.UIAxes
        UMAPTab         matlab.ui.container.Tab
        GridLayout8     matlab.ui.container.GridLayout
        UIAxes2_2       matlab.ui.control.UIAxes
        SHAPTab         matlab.ui.container.Tab
        GridLayout6     matlab.ui.container.GridLayout
        UIAxes          matlab.ui.control.UIAxes
        TabGroup        matlab.ui.container.TabGroup
        ClustersTab     matlab.ui.container.Tab
        GridClustPlots  matlab.ui.container.GridLayout
        CLUST_4         matlab.ui.control.UIAxes
        CLUST_3         matlab.ui.control.UIAxes
        CLUST_2         matlab.ui.control.UIAxes
        CLUST_1         matlab.ui.control.UIAxes
        MeanPlot        matlab.ui.container.Tab
        GridMeanPlot    matlab.ui.container.GridLayout
        MeanClust       matlab.ui.control.UIAxes
        WaveMapTab      matlab.ui.container.Tab
        OptionPanel     matlab.ui.container.Panel
        GridLayout      matlab.ui.container.GridLayout
        clusterFilesDD  matlab.ui.control.DropDown
        WaveMapButton   matlab.ui.control.Button
        NEXTFileButton  matlab.ui.control.Button
        LOADButton      matlab.ui.control.Button
    end


    properties (Access = private)
        spikeCaseLoc % Description
        spikeClustLoc
        spikeProcLoc
        ClusterList
        ROWnumber
        curWAVEfrm
        curWAVEpos
        curClusts
        curWaveFeats
        waveColors
        curSpkTime
        totalSecs
        
    end
    
    methods (Access = private)
        
        function updateCLUSTcheck(app , stage)

            switch stage
                case 'clear'

                    cla(app.MeanClust)
                    
                    for i = 1:4
                        plotID = ['CLUST_',num2str(i)];
                        cla(app.(plotID))
                        title(app.(plotID),'')
                    end

                    cla(app.featureMap)

                case 'start'

                    tnumClusts = sum(unique(app.curClusts) ~= 99999999);
                    tmpClustIDS = unique(app.curClusts);
                    cluID = tmpClustIDS(tmpClustIDS ~= 99999999);

                    [~,scores,~] = pca(app.curWaveFeats);

                    meanWave = zeros(height(app.curWAVEfrm),tnumClusts);
                    for i = 1:tnumClusts
                        plotID = ['CLUST_',num2str(i)];
                        clustTMP = cluID(i);
                        wavLocs = app.curClusts == clustTMP;
                        wavTmp = app.curWAVEfrm(:,wavLocs);

                        fRate = round(sum(wavLocs)/app.totalSecs,2);

                        if size(wavTmp,2) > 50
                            randSEL = randperm(size(wavTmp,2),50);
                            wavTmp2u = wavTmp(:,randSEL);
                        else
                            wavTmp2u = wavTmp;
                        end

                        plot(app.(plotID), wavTmp2u,'Color',[app.waveColors(i,:) 0.5])

                        xlim(app.(plotID),[1 height(app.curWAVEfrm)])

                        title(app.(plotID),['Cl ', num2str(i), ' = ',...
                            num2str(size(wavTmp,2)),...
                            ' | FR = ' , num2str(fRate),'Hz',...
                            ' | ',num2str(clustTMP)],'Color',[0.2 0.2 0.2],...
                            'FontSize',8);

                        meanWave(:,i) = mean(wavTmp2u,2);

                        hold(app.MeanClust,'on')
                        plot(app.MeanClust,meanWave(:,i),'Color',app.waveColors(i,:),...
                            'LineWidth',2.5)
                        hold(app.MeanClust,'off')
                        xlim(app.MeanClust,[1 height(app.curWAVEfrm)])

                        hold(app.featureMap,'on')
                        plot(app.featureMap,scores(wavLocs,1),scores(wavLocs,2),...
                            'Color',app.waveColors(i,:),'Marker','.','MarkerSize',20,...
                            'LineStyle','none')
                        xlabel(app.featureMap,'PC 1')
                        ylabel(app.featureMap,'PC 2')

                    end


                case 'update'


            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.MeanClust.Color = [0.2 0.2 0.2];
            app.featureMap.Color = [0.2 0.2 0.2];
            % app.VoltRawPlot.Color = [0.2 0.2 0.2];
            for ci = 1:4
                tmpClustIa = ['CLUST_' , num2str(ci)];
                app.(tmpClustIa).Color = [0.2 0.2 0.2];
            end

            RGB_w_Colors = [246, 189, 96  ;...
                247, 237, 226 ;...
                245, 202, 195 ;...
                189, 184, 176 ;...
                132, 165, 157 ;...
                242, 132, 130];
            app.waveColors = RGB_w_Colors/255;
        end

        % Menu selected function: DirectoryMenu
        function DirectoryMenuSelected(app, event)
            curDir = pwd;
            tmpRAWdata = uigetdir(curDir,'CHOOSE DATA DIRECTORY');
            app.spikeCaseLoc = tmpRAWdata;
            app.spikeProcLoc = [app.spikeCaseLoc , filesep , 'Processed Electrophysiology', filesep];
            app.spikeClustLoc = [app.spikeProcLoc , filesep , 'ClusteredSpikeTimes', filesep];

            % CD to Clustered folder
            cd(app.spikeClustLoc)

            % Get list
            allLIST1 = dir('*.mat');
            allLIST2 = {allLIST1.name};
            app.ClusterList = allLIST2;
            app.clusterFilesDD.Items = app.ClusterList;
            app.clusterFilesDD.Value = app.ClusterList{1};

            app.ROWnumber = 1;

            % Build table for spike and raw files
            % 1. spike name
            % 2. raw name
            % 3. electrode id
            % 4. depth id

            % app.depFile2load = app.FILESList.Items{1};
            % Get case name
            % firstPart = extractAfter(app.depFile2load,10);
            % app.DepthID = extractBefore(firstPart,'F');
            %
            % app.FileNameLabel.Text = app.depFile2load;
            % app.DepthnameLabel.Text = app.DepthID;

            %%%% ENABLE specific button FUNCTIONS
            % app.LoadCase.Enable = 'on';

            app.OptionPanel.Enable = 'on';

        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)

            % 1. Change directory to clustered files
            % 2. Load list into drop down menu

            cd(app.spikeClustLoc)
            load(app.clusterFilesDD.Value,'spikeClInfo');

            % Need Cluster ID, Waveforms
            app.curWAVEfrm = spikeClInfo.waveForms.waves;
            app.curWAVEpos = spikeClInfo.waveForms.positions;
            app.curClusts = spikeClInfo.clusterIDS;
            app.curWaveFeats = spikeClInfo.waveFeatures;
            app.curSpkTime = spikeClInfo.SpikeTSindex;
            app.totalSecs = (spikeClInfo.SpikeTSindex(end) -...
                spikeClInfo.SpikeTSindex(1))/44000;

            % % Create timeseries and Epoch
            % secEpoch = app.EpochDur.Value; % in seconds
            % 
            % app.numEpochs = floor(width(app.spikeDataRaw)/(app.spkFS*secEpoch));
            % app.EpochSpinner.Limits = [1 app.numEpochs];
            % % app.maxEp.Value = app.numEpochs;  %%%%%%%%%%%%%%% CONSIDER ADDING
            % startINDS = transpose(1:app.spkFS*secEpoch:(app.spkFS*app.numEpochs*secEpoch));
            % stopINDS = [startINDS(2:end)-1 ; width(app.spikeDataRaw)];
            % app.EpochInds = [startINDS , stopINDS];
            % app.CurEpoch = 1;
            % app.MaxEpochsEditField.Value = app.numEpochs;
            % app.totalSecs = width(app.spikeDataRaw)/app.spkFS;

            % FUNCTION TO prep timeseries epoch
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            updateCLUSTcheck(app , 'start')


        end

        % Button pushed function: NEXTFileButton
        function NEXTFileButtonPushed(app, event)
            
            cd(app.spikeClustLoc)

            app.ROWnumber = app.ROWnumber + 1;

            if app.ROWnumber > length(app.clusterFilesDD.Items)
                return
            end

            app.clusterFilesDD.ValueIndex = app.ROWnumber;

            load(app.clusterFilesDD.Value,'spikeClInfo');

            % Need Cluster ID, Waveforms
            app.curWAVEfrm = spikeClInfo.waveForms.waves;
            app.curWAVEpos = spikeClInfo.waveForms.positions;
            app.curClusts = spikeClInfo.clusterIDS;
            app.curWaveFeats = spikeClInfo.waveFeatures;
            app.curSpkTime = spikeClInfo.SpikeTSindex;
            app.totalSecs = (spikeClInfo.SpikeTSindex(end) -...
                spikeClInfo.SpikeTSindex(1))/44000;

            updateCLUSTcheck(app , 'clear')
            updateCLUSTcheck(app , 'start')


        end

        % Value changed function: clusterFilesDD
        function clusterFilesDDValueChanged(app, event)
            
            app.ROWnumber = app.clusterFilesDD.ValueIndex;
            cd(app.spikeClustLoc)

            load(app.clusterFilesDD.Value,'spikeClInfo');

            % Need Cluster ID, Waveforms
            app.curWAVEfrm = spikeClInfo.waveForms.waves;
            app.curWAVEpos = spikeClInfo.waveForms.positions;
            app.curClusts = spikeClInfo.clusterIDS;
            app.curWaveFeats = spikeClInfo.waveFeatures;
            app.curSpkTime = spikeClInfo.SpikeTSindex;
            app.totalSecs = (spikeClInfo.SpikeTSindex(end) -...
                spikeClInfo.SpikeTSindex(1))/44000;

            updateCLUSTcheck(app , 'clear')
            updateCLUSTcheck(app , 'start')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DirectoryMenu = uimenu(app.UIFigure);
            app.DirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @DirectoryMenuSelected, true);
            app.DirectoryMenu.Text = 'Directory';
            app.OptionPanel = uipanel(app.UIFigure);
            app.OptionPanel.Enable = 'off';
            app.GridLayout = uigridlayout(app.OptionPanel);
            app.LOADButton = uibutton(app.GridLayout, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.NEXTFileButton = uibutton(app.GridLayout, 'push');
            app.NEXTFileButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTFileButtonPushed, true);
            app.NEXTFileButton.Text = 'NEXT File';
            app.WaveMapButton = uibutton(app.GridLayout, 'push');
            app.WaveMapButton.Text = 'WaveMap';
            app.clusterFilesDD = uidropdown(app.GridLayout);
            app.clusterFilesDD.ValueChangedFcn = createCallbackFcn(app, @clusterFilesDDValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.ClustersTab = uitab(app.TabGroup);
            app.ClustersTab.Title = 'Clusters';
            app.GridClustPlots = uigridlayout(app.ClustersTab);
            app.CLUST_1 = uiaxes(app.GridClustPlots);
            zlabel(app.CLUST_1, 'Z')
            app.CLUST_1.XTick = [];
            app.CLUST_1.YTick = [];
            app.CLUST_2 = uiaxes(app.GridClustPlots);
            zlabel(app.CLUST_2, 'Z')
            app.CLUST_2.XTick = [];
            app.CLUST_2.YTick = [];
            app.CLUST_3 = uiaxes(app.GridClustPlots);
            zlabel(app.CLUST_3, 'Z')
            app.CLUST_3.XTick = [];
            app.CLUST_3.YTick = [];
            app.CLUST_4 = uiaxes(app.GridClustPlots);
            zlabel(app.CLUST_4, 'Z')
            app.CLUST_4.XTick = [];
            app.CLUST_4.YTick = [];
            app.MeanPlot = uitab(app.TabGroup);
            app.MeanPlot.Title = 'MeanPlot';
            app.GridMeanPlot = uigridlayout(app.MeanPlot);
            app.MeanClust = uiaxes(app.GridMeanPlot);
            zlabel(app.MeanClust, 'Z')
            app.MeanClust.XTick = [];
            app.MeanClust.YTick = [];
            app.WaveMapTab = uitab(app.TabGroup);
            app.WaveMapTab.Title = 'WaveMap';
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.FeaturesTab = uitab(app.TabGroup2);
            app.FeaturesTab.Title = 'Features';
            app.GridLayout9 = uigridlayout(app.FeaturesTab);
            app.featureMap = uiaxes(app.GridLayout9);
            zlabel(app.featureMap, 'Z')
            app.featureMap.XTick = [];
            app.featureMap.YTick = [];
            app.UMAPTab = uitab(app.TabGroup2);
            app.UMAPTab.Title = 'UMAP';
            app.GridLayout8 = uigridlayout(app.UMAPTab);
            app.UIAxes2_2 = uiaxes(app.GridLayout8);
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.SHAPTab = uitab(app.TabGroup2);
            app.SHAPTab.Title = 'SHAP';
            app.GridLayout6 = uigridlayout(app.SHAPTab);
            app.UIAxes = uiaxes(app.GridLayout6);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TimelinePanel = uipanel(app.UIFigure);
            app.TimelinePanel.Title = 'Timeline';
            app.GridLayout3 = uigridlayout(app.TimelinePanel);
            app.UIAxes3 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3_2 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            app.EpochPanel = uipanel(app.GridLayout3);
            app.EpochPanel.Title = 'Epoch';
            app.GridLayout4 = uigridlayout(app.EpochPanel);
            app.BACKButton = uibutton(app.GridLayout4, 'push');
            app.BACKButton.Text = 'BACK';
            app.NEXTButton = uibutton(app.GridLayout4, 'push');
            app.NEXTButton.Text = 'NEXT';
            app.EPOCHLabel = uilabel(app.GridLayout4);
            app.EPOCHLabel.Text = 'EPOCH';
            app.NUMLabel = uilabel(app.GridLayout4);
            app.NUMLabel.Text = 'NUM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeQualityRView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end